classdef klikac < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        bmaxEditField       matlab.ui.control.NumericEditField
        bmaxEditFieldLabel  matlab.ui.control.Label
        bminEditField       matlab.ui.control.NumericEditField
        bminEditFieldLabel  matlab.ui.control.Label
        gmaxEditField       matlab.ui.control.NumericEditField
        gmaxEditFieldLabel  matlab.ui.control.Label
        gminEditField       matlab.ui.control.NumericEditField
        gminEditFieldLabel  matlab.ui.control.Label
        rmaxEditField       matlab.ui.control.NumericEditField
        rmaxEditFieldLabel  matlab.ui.control.Label
        rminEditField       matlab.ui.control.NumericEditField
        rminEditFieldLabel  matlab.ui.control.Label
        OKButton            matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img;
        segmentation;
        result;
        img_image;
        contours1;
        contours2;
        filename;
    end
    
    methods (Access = public)
        
        function update_contours(app)
    
            try
                delete(app.contours1)
            end
            try
                delete(app.contours2)
            end

            app.contours1 = visboundaries(app.UIAxes,app.result==1,'Color','r','EnhanceVisibility',0,'LineWidth',1);
            app.contours2 = visboundaries(app.UIAxes,app.result==2,'Color','g','EnhanceVisibility',0,'LineWidth',1);


        end
        
        function update_img(app)
            
            try
                delete(app.img_image)
            end


            tmp = app.img;
            tmp(:,:,1) = mat2gray(tmp(:,:,1),[app.rminEditField.Value,app.rmaxEditField.Value]);
            tmp(:,:,2) = mat2gray(tmp(:,:,2),[app.gminEditField.Value,app.gmaxEditField.Value]);
            tmp(:,:,3) = mat2gray(tmp(:,:,3),[app.bminEditField.Value,app.bmaxEditField.Value]);
            app.img_image = imshow(tmp,'Parent',app.UIAxes);
            hold(app.UIAxes, 'off')
            title(app.UIAxes,app.filename)
%             
            
            app.update_contours();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filename)
            
            app.filename = filename;
            data = load(filename);
            app.img = data.data;
            app.segmentation = imresize(data.segmentation,[size(app.img,1) size(app.img,2)],"nearest");
            clear data;
            app.result = uint8(app.segmentation > 0);


            app.update_img();

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            try 
                if isequal(event.Source.CurrentObject.Parent,app.UIAxes)  
                    object_x = event.Source.CurrentObject.Parent;
                else
                    if isequal(event.Source.CurrentObject.Parent.Parent,app.UIAxes)
                        object_x = event.Source.CurrentObject.Parent.Parent;
                    else
                        return
                    end
                end
            catch
                return
            end

            position=object_x.CurrentPoint;
             position=[round(position(1,1)),round(position(1,2))];


             if strcmp(app.UIFigure.SelectionType,'normal')
                 tmp =2;
             elseif strcmp(app.UIFigure.SelectionType,'alt')
                 tmp = 1;
             elseif strcmp(app.UIFigure.SelectionType,'extend')
                 tmp = 2;
             else
                 tmp = 2;
             end


             value = app.segmentation(position(2),position(1));

             if value > 0
                app.result(app.segmentation == value) = tmp;
             end

            app.update_contours();


        end

        % Value changed function: bmaxEditField, bminEditField, 
        % gmaxEditField, gminEditField, rmaxEditField, rminEditField
        function bminEditFieldValueChanged(app, event)
            app.update_img();


        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            result = app.result;
            filename_save = [ fileparts(app.filename) '/rad51_res.mat'];
            save(filename_save,'result');
            
            app.delete;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.rminEditFieldLabel = uilabel(app.UIFigure);
            app.rminEditFieldLabel.Text = 'r min';
            app.rminEditField = uieditfield(app.UIFigure, 'numeric');
            app.rminEditField.ValueChangedFcn = createCallbackFcn(app, @bminEditFieldValueChanged, true);
            app.rmaxEditFieldLabel = uilabel(app.UIFigure);
            app.rmaxEditFieldLabel.Text = 'r max';
            app.rmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.rmaxEditField.ValueChangedFcn = createCallbackFcn(app, @bminEditFieldValueChanged, true);
            app.rmaxEditField.Value = 1;
            app.gminEditFieldLabel = uilabel(app.UIFigure);
            app.gminEditFieldLabel.Text = 'g min';
            app.gminEditField = uieditfield(app.UIFigure, 'numeric');
            app.gminEditField.ValueChangedFcn = createCallbackFcn(app, @bminEditFieldValueChanged, true);
            app.gmaxEditFieldLabel = uilabel(app.UIFigure);
            app.gmaxEditFieldLabel.Text = 'g max';
            app.gmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.gmaxEditField.ValueChangedFcn = createCallbackFcn(app, @bminEditFieldValueChanged, true);
            app.gmaxEditField.Value = 1;
            app.bminEditFieldLabel = uilabel(app.UIFigure);
            app.bminEditFieldLabel.Text = 'b min';
            app.bminEditField = uieditfield(app.UIFigure, 'numeric');
            app.bminEditField.ValueChangedFcn = createCallbackFcn(app, @bminEditFieldValueChanged, true);
            app.bmaxEditFieldLabel = uilabel(app.UIFigure);
            app.bmaxEditFieldLabel.Text = 'b max';
            app.bmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.bmaxEditField.ValueChangedFcn = createCallbackFcn(app, @bminEditFieldValueChanged, true);
            app.bmaxEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = klikac(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end