classdef semiauto_appdes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        okButton       matlab.ui.control.Button
        dropButton     matlab.ui.control.Button
        rSpinner_2     matlab.ui.control.Spinner
        gSpinner_2     matlab.ui.control.Spinner
        bSpinner_2     matlab.ui.control.Spinner
        rSpinnerLabel  matlab.ui.control.Label
        rSpinner       matlab.ui.control.Spinner
        gSpinnerLabel  matlab.ui.control.Label
        gSpinner       matlab.ui.control.Spinner
        bSpinnerLabel  matlab.ui.control.Label
        bSpinner       matlab.ui.control.Spinner
        axes_main      matlab.ui.control.UIAxes
        axes_bottom    matlab.ui.control.UIAxes
        axes_right     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        pos
        binaryResuslts
        rImg_main
        gImg_main
        bImg_main
        rImg_bottom
        gImg_bottom
        bImg_bottom
        rImg_right
        gImg_right
        bImg_right
        rMin
        rMax
        gMin
        gMax
        bMin
        bMax
        drop
        maskNuklei_main
        maskNuklei_bottom
        maskNuklei_right
        maskFoci_main
        maskFoci_bottom
        maskFoci_right
        resize_factor
        p_m_1
        p_m_2
        p_m_3
        p_b_1
        p_b_2
        p_b_3
        p_r_1
        p_r_2
        p_r_3
        img_main
        img_bottom
        img_right
        name
    end
    
    
    methods (Access = private)
        
        function update_all(app)

            hold(app.axes_main, 'off')
            color=cat(3,mat2gray(app.rImg_main,[app.rMin*app.rSpinner.Value  app.rMax*app.rSpinner_2.Value]),...
                mat2gray(app.gImg_main,[app.gMin*app.gSpinner.Value  app.gMax*app.gSpinner_2.Value]),...
                mat2gray(app.bImg_main,[app.bMin*app.bSpinner.Value  app.bMax*app.bSpinner_2.Value]));
            app.img_main=imshow(color,'Parent',app.axes_main);
            app.img_main.Tag='MousePress_main';
            title(app.axes_main,app.name)

            
            hold(app.axes_bottom, 'off')
            color=cat(3,mat2gray(app.rImg_bottom,[app.rMin*app.rSpinner.Value  app.rMax*app.rSpinner_2.Value]),...
                mat2gray(app.gImg_bottom,[app.gMin*app.gSpinner.Value  app.gMax*app.gSpinner_2.Value]),...
                mat2gray(app.bImg_bottom,[app.bMin*app.bSpinner.Value  app.bMax*app.bSpinner_2.Value]));
            app.img_bottom=imshow(color,'Parent',app.axes_bottom);
            app.img_bottom.Tag='MousePress_bottom';
            
            hold(app.axes_main, 'off')
            color=cat(3,mat2gray(app.rImg_right,[app.rMin*app.rSpinner.Value  app.rMax*app.rSpinner_2.Value]),...
                mat2gray(app.gImg_right,[app.gMin*app.gSpinner.Value  app.gMax*app.gSpinner_2.Value]),...
                mat2gray(app.bImg_right,[app.bMin*app.bSpinner.Value  app.bMax*app.bSpinner_2.Value]));
            app.img_right=imshow(color,'Parent',app.axes_right);
            app.img_right.Tag='MousePress_right';

            update_points(app)
    
            
        end
        
        function update_points(app)
        
        
            hold(app.axes_main, 'on')
            if ~isempty(app.p_m_1)
                delete(app.p_m_1);delete(app.p_m_2);delete(app.p_m_3);
            end
            app.p_m_1=plot(app.axes_main,app.pos(:,1), app.pos(:,2), 'b*','Tag', 'MousePress_main','MarkerSize',3);
            app.p_m_2=plot(app.axes_main,app.pos(find(app.binaryResuslts),1), app.pos(find(app.binaryResuslts),2), 'ro','Tag', 'MousePress_main','MarkerSize',3);
            app.p_m_3=plot(app.axes_main,app.pos(find(app.binaryResuslts),1), app.pos(find(app.binaryResuslts),2), 'g*','Tag', 'MousePress_main','MarkerSize',3);


            hold(app.axes_bottom, 'on')
            if ~isempty(app.p_b_1)
                delete(app.p_b_1);delete(app.p_b_2);delete(app.p_b_3);
            end
            app.p_b_1=plot(app.axes_bottom,app.pos(:,1), app.pos(:,3), 'b*','Tag', 'MousePress_bottom','MarkerSize',3);
            app.p_b_2=plot(app.axes_bottom,app.pos(find(app.binaryResuslts),1), app.pos(find(app.binaryResuslts),3), 'ro','Tag', 'MousePress_bottom','MarkerSize',3);
            app.p_b_3=plot(app.axes_bottom,app.pos(find(app.binaryResuslts),1), app.pos(find(app.binaryResuslts),3), 'g*','Tag', 'MousePress_bottom','MarkerSize',3);
            

            hold(app.axes_right, 'on')
            if ~isempty(app.p_r_1)
                delete(app.p_r_1);delete(app.p_r_2);delete(app.p_r_2);
            end
            app.p_r_1=plot(app.axes_right,app.pos(:,3), app.pos(:,2), 'b*','Tag', 'MousePress_right','MarkerSize',3);
            app.p_r_2=plot(app.axes_right,app.pos(find(app.binaryResuslts),3), app.pos(find(app.binaryResuslts),2), 'ro','Tag', 'MousePress_right','MarkerSize',3);
            app.p_r_3=plot(app.axes_right,app.pos(find(app.binaryResuslts),3), app.pos(find(app.binaryResuslts),2), 'g*','Tag', 'MousePress_right','MarkerSize',3);


        end
        
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, r, g, b, mask_nuclei, mask_foci, name)
            drawnow;
            app.UIFigure.WindowState = 'maximized';

            tmp =regionprops3(mask_foci>0,"Centroid");
            app.pos= tmp.Centroid;  
            
            if isempty(app.pos)
                 app.pos=zeros(0,3);
            end
            
            app.binaryResuslts=zeros(1,size(app.pos,1));
            
            
            app.rImg_main=max(r,[],3);
            app.gImg_main=max(g,[],3);
            app.bImg_main=max(b,[],3);
            
            app.rImg_right=squeeze(max(r,[],2));
            app.gImg_right=squeeze(max(g,[],2));
            app.bImg_right=squeeze(max(b,[],2));
            
            app.rImg_bottom=squeeze(max(r,[],1))';
            app.gImg_bottom=squeeze(max(g,[],1))';
            app.bImg_bottom=squeeze(max(b,[],1))';
            

            app.rMin=prctile(app.rImg_main(:),0.1);
            app.rMax=prctile(app.rImg_main(:),100-0.1);
            app.gMin=prctile(app.gImg_main(:),0.1);
            app.gMax=prctile(app.gImg_main(:),100-0.1);
            app.bMin=prctile(app.bImg_main(:),0.1);
            app.bMax=prctile(app.bImg_main(:),100-0.1);

            
            app.drop=0;
            
            app.maskNuklei_main=max(mask_nuclei,[],3);
            app.maskNuklei_bottom=squeeze(max(mask_nuclei,[],2));
            app.maskNuklei_right=squeeze(max(mask_nuclei,[],1))';
            
            app.maskFoci_main=max(mask_nuclei,[],3);
            app.maskFoci_bottom=squeeze(max(mask_nuclei,[],2));
            app.maskFoci_right=squeeze(max(mask_nuclei,[],1))';


            app.resize_factor=4;
            
            app.rImg_right=imresize(app.rImg_right,[size(app.rImg_right,1),size(app.rImg_right,2)*app.resize_factor]);
            app.gImg_right=imresize(app.gImg_right,[size(app.gImg_right,1),size(app.gImg_right,2)*app.resize_factor]);
            app.bImg_right=imresize(app.bImg_right,[size(app.bImg_right,1),size(app.bImg_right,2)*app.resize_factor]);
            
            app.rImg_bottom=imresize(app.rImg_bottom,[size(app.rImg_bottom,1)*app.resize_factor,size(app.rImg_bottom,2)]);
            app.gImg_bottom=imresize(app.gImg_bottom,[size(app.gImg_bottom,1)*app.resize_factor,size(app.gImg_bottom,2)]);
            app.bImg_bottom=imresize(app.bImg_bottom,[size(app.bImg_bottom,1)*app.resize_factor,size(app.bImg_bottom,2)]);
            
            
            app.pos(:,3)=app.pos(:,3)*app.resize_factor;
            
            app.name=name;
            
            app.update_all()

            uiwait(app.UIFigure)
            
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            app.drop=0;
            app.pos(:,3)=app.pos(:,3)/app.resize_factor;
            
            positions=app.pos;
            labels=app.binaryResuslts;
            
            assignin('base','positions',positions)
            assignin('base','labels',labels)
            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.drop=1;
            delete(app)
        end

        % Button pushed function: dropButton
        function dropButtonPushed(app, event)
            app.drop=1;
            delete(app)
        end

        % Value changed function: rSpinner
        function rSpinnerValueChanged(app, event)
            app.update_all() 
        end

        % Value changed function: rSpinner_2
        function rSpinner_2ValueChanged(app, event)
            app.update_all()
        end

        % Value changed function: gSpinner
        function gSpinnerValueChanged(app, event)
            app.update_all()
        end

        % Value changed function: gSpinner_2
        function gSpinner_2ValueChanged(app, event)
            app.update_all()
        end

        % Value changed function: bSpinner
        function bSpinnerValueChanged(app, event)
            app.update_all()
        end

        % Value changed function: bSpinner_2
        function bSpinner_2ValueChanged(app, event)
            app.update_all()
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            try 
                 if isequal(event.Source.CurrentObject.Tag,'MousePress_main')
                     position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                     position=[position(1,1),position(1,2)];
                    
                     [min_dist,closest]=min((app.pos(:,1)-position(1)).^2+(app.pos(:,2)-position(2)).^2);
                     if min_dist<30^2
                         if strcmp(app.UIFigure.SelectionType,'normal')
                             tmp=1;
                         elseif strcmp(app.UIFigure.SelectionType,'alt')
                             tmp=0;                              
                         end
                     end
                     app.binaryResuslts(closest)=tmp;
                     app.update_points()
                 end
                 if isequal(event.Source.CurrentObject.Tag,'MousePress_bottom')
                     position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                     position=[position(1,1),position(1,2)];
                    
                     [min_dist,closest]=min((app.pos(:,1)-position(1)).^2+(app.pos(:,3)-position(2)).^2);
                     if min_dist<30^2
                         if strcmp(app.UIFigure.SelectionType,'normal')
                             tmp=1;
                         elseif strcmp(app.UIFigure.SelectionType,'alt')
                             tmp=0;                              
                         end
                     end
                     app.binaryResuslts(closest)=tmp;
                     app.update_points()
                 end
                 if isequal(event.Source.CurrentObject.Tag,'MousePress_right')
                     position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                     position=[position(1,1),position(1,2)];
                    
                     [min_dist,closest]=min((app.pos(:,3)-position(1)).^2+(app.pos(:,2)-position(2)).^2);
                     if min_dist<30^2
                         if strcmp(app.UIFigure.SelectionType,'normal')
                             tmp=1;
                         elseif strcmp(app.UIFigure.SelectionType,'alt')
                             tmp=0;                              
                         end
                     end
                     app.binaryResuslts(closest)=tmp;
                     app.update_points()
                 end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.okButton = uibutton(app.UIFigure, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'ok';
            app.dropButton = uibutton(app.UIFigure, 'push');
            app.dropButton.ButtonPushedFcn = createCallbackFcn(app, @dropButtonPushed, true);
            app.dropButton.Text = 'drop';
            app.rSpinner_2 = uispinner(app.UIFigure);
            app.rSpinner_2.Step = 0.1;
            app.rSpinner_2.Limits = [0.1 10];
            app.rSpinner_2.ValueChangedFcn = createCallbackFcn(app, @rSpinner_2ValueChanged, true);
            app.rSpinner_2.Value = 1;
            app.gSpinner_2 = uispinner(app.UIFigure);
            app.gSpinner_2.Step = 0.1;
            app.gSpinner_2.Limits = [0.1 10];
            app.gSpinner_2.ValueChangedFcn = createCallbackFcn(app, @gSpinner_2ValueChanged, true);
            app.gSpinner_2.Value = 1;
            app.bSpinner_2 = uispinner(app.UIFigure);
            app.bSpinner_2.Step = 0.1;
            app.bSpinner_2.Limits = [0.1 10];
            app.bSpinner_2.ValueChangedFcn = createCallbackFcn(app, @bSpinner_2ValueChanged, true);
            app.bSpinner_2.Value = 1;
            app.rSpinnerLabel = uilabel(app.UIFigure);
            app.rSpinnerLabel.Text = 'r ';
            app.rSpinner = uispinner(app.UIFigure);
            app.rSpinner.Step = 0.1;
            app.rSpinner.Limits = [0 10];
            app.rSpinner.ValueChangedFcn = createCallbackFcn(app, @rSpinnerValueChanged, true);
            app.rSpinner.Value = 1;
            app.gSpinnerLabel = uilabel(app.UIFigure);
            app.gSpinnerLabel.Text = 'g ';
            app.gSpinner = uispinner(app.UIFigure);
            app.gSpinner.Step = 0.1;
            app.gSpinner.Limits = [0 10];
            app.gSpinner.ValueChangedFcn = createCallbackFcn(app, @gSpinnerValueChanged, true);
            app.gSpinner.Value = 1;
            app.bSpinnerLabel = uilabel(app.UIFigure);
            app.bSpinnerLabel.Text = 'b ';
            app.bSpinner = uispinner(app.UIFigure);
            app.bSpinner.Step = 0.1;
            app.bSpinner.Limits = [0 10];
            app.bSpinner.ValueChangedFcn = createCallbackFcn(app, @bSpinnerValueChanged, true);
            app.bSpinner.Value = 1;
            app.axes_main = uiaxes(app.UIFigure);
            title(app.axes_main, 'Title')
            xlabel(app.axes_main, 'X')
            ylabel(app.axes_main, 'Y')
            app.axes_bottom = uiaxes(app.UIFigure);
            title(app.axes_bottom, 'Title')
            xlabel(app.axes_bottom, 'X')
            ylabel(app.axes_bottom, 'Y')
            app.axes_right = uiaxes(app.UIFigure);
            title(app.axes_right, 'Title')
            xlabel(app.axes_right, 'X')
            ylabel(app.axes_right, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = semiauto_appdes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end