classdef line_labeller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        OKButton            matlab.ui.control.Button
        BmaxSpinner_2       matlab.ui.control.Spinner
        BmaxSpinner_2Label  matlab.ui.control.Label
        GmaxSpinner_2       matlab.ui.control.Spinner
        GmaxSpinner_2Label  matlab.ui.control.Label
        RmaxSpinner_2       matlab.ui.control.Spinner
        RmaxSpinner_2Label  matlab.ui.control.Label
        BminSpinner_2       matlab.ui.control.Spinner
        BminSpinner_2Label  matlab.ui.control.Label
        GminSpinner_2       matlab.ui.control.Spinner
        GminSpinner_2Label  matlab.ui.control.Label
        RminSpinner_2       matlab.ui.control.Spinner
        RminSpinner_2Label  matlab.ui.control.Label
        BmaxSpinner         matlab.ui.control.Spinner
        BmaxSpinnerLabel    matlab.ui.control.Label
        GmaxSpinner         matlab.ui.control.Spinner
        GmaxSpinnerLabel    matlab.ui.control.Label
        RmaxSpinner         matlab.ui.control.Spinner
        RmaxSpinnerLabel    matlab.ui.control.Label
        BminSpinner         matlab.ui.control.Spinner
        BminSpinnerLabel    matlab.ui.control.Label
        GminSpinner         matlab.ui.control.Spinner
        GminSpinnerLabel    matlab.ui.control.Label
        RminSpinner         matlab.ui.control.Spinner
        RminSpinnerLabel    matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data;
        detections;
        lines;
        img_main;
        points_main;
        actual_line;
        actual_point;

    end
    
    methods (Access = private)
        
        function show(app)
            color=cat(3,...
                mat2gray(app.data.rImg_main,[app.RminSpinner.Value  app.RmaxSpinner.Value]),...
                mat2gray(app.data.gImg_main,[app.GminSpinner.Value  app.GmaxSpinner.Value]),...
                mat2gray(app.data.bImg_main,[app.BminSpinner.Value  app.BmaxSpinner.Value]));

            hold(app.UIAxes, 'off')
            app.img_main=imshow(color,'Parent',app.UIAxes);
            title(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlabel(app.UIAxes,'')

            hold(app.UIAxes, 'on')

            app.points_main=plot(app.UIAxes,app.detections(:,1), app.detections(:,2), 'b*','MarkerSize',3);

            if ~isempty(app.lines)
                for k = 1:length(app.lines)
                    tmp = app.lines{k};
                    plot(app.UIAxes,app.detections(tmp,1), app.detections(tmp,2), 'yo','MarkerSize',3);
                    plot(app.UIAxes,app.detections(tmp,1), app.detections(tmp,2), 'y-');
                end
            end

            tmp = app.actual_line;
            plot(app.UIAxes,app.detections(tmp,1), app.detections(tmp,2), 'ro','MarkerSize',3);
            plot(app.UIAxes,app.detections(tmp,1), app.detections(tmp,2), 'r-');

            
        end

        function init_show_ranges(app)
            
            app.RminSpinner.Value =  double(prctile(app.data.rImg_main,app.RminSpinner_2.Value,'all'));
            app.RmaxSpinner.Value =  double(prctile(app.data.rImg_main,100-app.RmaxSpinner_2.Value,'all'));
            
            app.GminSpinner.Value =  double(prctile(app.data.gImg_main,app.GminSpinner_2.Value,'all'));
            app.GmaxSpinner.Value =  double(prctile(app.data.gImg_main,100-app.GmaxSpinner_2.Value,'all'));
            
            app.BminSpinner.Value =  double(prctile(app.data.bImg_main,app.BminSpinner_2.Value,'all'));
            app.BmaxSpinner.Value =  double(prctile(app.data.bImg_main,100-app.BmaxSpinner_2.Value,'all'));
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, detections)
            app.data = struct();
            app.data.rImg_main = max(data{1},[],3);
            app.data.gImg_main = max(data{2},[],3);
            app.data.bImg_main = max(data{3},[],3);

            app.detections = detections;
            app.lines = {};
            app.actual_line = [];

            app.init_show_ranges()
            app.show();

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if isequal(event.Source.CurrentObject.Parent,app.UIAxes)
                 position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                 position=[position(1,1),position(1,2)];

                 [min_dist,nearest_point_ind] = min((app.detections(:,1)-position(1)).^2+(app.detections(:,2)-position(2)).^2);
                 nearest_point = app.detections(nearest_point_ind,:);
                 app.actual_point = nearest_point_ind;
                 if min_dist<30^2 

                     if strcmp(app.UIFigure.SelectionType,'normal')%left 
                         %continue_line

                         is_in_some_line = any([app.lines{:}] == nearest_point_ind);
                         if ~is_in_some_line
                            app.actual_line = [app.actual_line, nearest_point_ind];
                         end
    
                     elseif strcmp(app.UIFigure.SelectionType,'alt')%right
                         %init_new_line or continue with existing
                         if isempty(app.actual_line)
                            
                         else
                            app.lines = [app.lines, {app.actual_line}];
                         end
                         
                         is_in_some_line = any([app.lines{:}] == nearest_point_ind);
                         if is_in_some_line
                            tmp_line = app.lines(find(cellfun(@(x) any(x == nearest_point_ind),app.lines)));
                            app.actual_line = tmp_line;
                         else
                            app.actual_line = [nearest_point_ind];
                         end
    
                     elseif strcmp(app.UIFigure.SelectionType,'extend')%shift+click
                         %remove_point
                        for k = 1:length(app.lines)
                            tmp = app.lines{k};
                            tmp(tmp == nearest_point_ind) = [];
                            app.lines{k} = tmp;
                        end
                        tmp = app.actual_line;
                        tmp(tmp == nearest_point_ind) = [];
                        app.actual_line = tmp;

                     end

                 end

                 app.show();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RminSpinnerLabel = uilabel(app.UIFigure);
            app.RminSpinnerLabel.Text = 'Rmin';
            app.RminSpinner = uispinner(app.UIFigure);
            app.RminSpinner.Step = 5;
            app.GminSpinnerLabel = uilabel(app.UIFigure);
            app.GminSpinnerLabel.Text = 'Gmin';
            app.GminSpinner = uispinner(app.UIFigure);
            app.GminSpinner.Step = 5;
            app.BminSpinnerLabel = uilabel(app.UIFigure);
            app.BminSpinnerLabel.Text = 'Bmin';
            app.BminSpinner = uispinner(app.UIFigure);
            app.BminSpinner.Step = 5;
            app.RmaxSpinnerLabel = uilabel(app.UIFigure);
            app.RmaxSpinnerLabel.Text = 'Rmax';
            app.RmaxSpinner = uispinner(app.UIFigure);
            app.RmaxSpinner.Step = 5;
            app.GmaxSpinnerLabel = uilabel(app.UIFigure);
            app.GmaxSpinnerLabel.Text = 'Gmax';
            app.GmaxSpinner = uispinner(app.UIFigure);
            app.GmaxSpinner.Step = 5;
            app.BmaxSpinnerLabel = uilabel(app.UIFigure);
            app.BmaxSpinnerLabel.Text = 'Bmax';
            app.BmaxSpinner = uispinner(app.UIFigure);
            app.BmaxSpinner.Step = 5;
            app.RminSpinner_2Label = uilabel(app.UIFigure);
            app.RminSpinner_2Label.Text = 'Rmin';
            app.RminSpinner_2 = uispinner(app.UIFigure);
            app.RminSpinner_2.Step = 0.2;
            app.RminSpinner_2.Limits = [0 100];
            app.RminSpinner_2.Value = 0.2;
            app.GminSpinner_2Label = uilabel(app.UIFigure);
            app.GminSpinner_2Label.Text = 'Gmin';
            app.GminSpinner_2 = uispinner(app.UIFigure);
            app.GminSpinner_2.Step = 0.2;
            app.GminSpinner_2.Limits = [0 100];
            app.GminSpinner_2.Value = 0.2;
            app.BminSpinner_2Label = uilabel(app.UIFigure);
            app.BminSpinner_2Label.Text = 'Bmin';
            app.BminSpinner_2 = uispinner(app.UIFigure);
            app.BminSpinner_2.Step = 0.2;
            app.BminSpinner_2.Limits = [0 100];
            app.BminSpinner_2.Value = 0.2;
            app.RmaxSpinner_2Label = uilabel(app.UIFigure);
            app.RmaxSpinner_2Label.Text = 'Rmax';
            app.RmaxSpinner_2 = uispinner(app.UIFigure);
            app.RmaxSpinner_2.Step = 0.2;
            app.RmaxSpinner_2.Limits = [0 100];
            app.RmaxSpinner_2.Value = 1;
            app.GmaxSpinner_2Label = uilabel(app.UIFigure);
            app.GmaxSpinner_2Label.Text = 'Gmax';
            app.GmaxSpinner_2 = uispinner(app.UIFigure);
            app.GmaxSpinner_2.Step = 0.2;
            app.GmaxSpinner_2.Limits = [0 100];
            app.GmaxSpinner_2.Value = 1;
            app.BmaxSpinner_2Label = uilabel(app.UIFigure);
            app.BmaxSpinner_2Label.Text = 'Bmax';
            app.BmaxSpinner_2 = uispinner(app.UIFigure);
            app.BmaxSpinner_2.Step = 0.2;
            app.BmaxSpinner_2.Limits = [0 100];
            app.BmaxSpinner_2.Value = 1;
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = line_labeller(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end