classdef artery_vein_labeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        rightclickLabel        matlab.ui.control.Label
        shiftclickLabel        matlab.ui.control.Label
        leftclickLabel         matlab.ui.control.Label
        ButtonGroup            matlab.ui.container.ButtonGroup
        zoomresetxButton       matlab.ui.control.Button
        zoomonyButton          matlab.ui.control.Button
        zoomoffButton          matlab.ui.control.Button
        EditField_custom       matlab.ui.control.EditField
        EditField_eqGB         matlab.ui.control.EditField
        EditField_eqG          matlab.ui.control.EditField
        EditField_eq           matlab.ui.control.EditField
        EditField_original     matlab.ui.control.EditField
        custom5Button          matlab.ui.control.RadioButton
        equalizedGB4Button     matlab.ui.control.RadioButton
        equalizedG3Button      matlab.ui.control.RadioButton
        equalized2Button       matlab.ui.control.RadioButton
        original1Button        matlab.ui.control.RadioButton
        joinjButton            matlab.ui.control.Button
        splitsButton           matlab.ui.control.Button
        redoButton             matlab.ui.control.Button
        undozButton            matlab.ui.control.Button
        showlinesctrlCheckBox  matlab.ui.control.CheckBox
        loadButton             matlab.ui.control.Button
        saveButton             matlab.ui.control.Button
        OKButton               matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img_name 
        mask_name
        img
        mask
        img_image
        splitmask
        min_len
        contour_line_width
        contours
        history_len
        history
        click_on
        label
        user_name
        colors
        history_position 
        tmp_img
        positions_X
        positions_Y
        labels
        dilatation
        img2show
        resize
    end
    
    methods (Access = public)
        
        function show_img(app)
            hold(app.UIAxes, 'off')

            app.img_image = imshow(app.img2show,'Parent',app.UIAxes);
            drawnow;
        end
        
        function show_lines(app)
            
            for k=1:length(app.contours)
                c=app.contours(k);
                delete(c)
            end


            hold(app.UIAxes, 'on')
            app.contours = {};
            for k = 1:length(app.colors)
                tmp = visboundaries(app.UIAxes,app.splitmask==k,'Color',app.colors{k},'EnhanceVisibility',0,'LineWidth',app.contour_line_width);
                app.contours=[app.contours,tmp];
            end
            
            hold(app.UIAxes, 'off')
            drawnow;
        end
        function add_line(app,object,color_num)
            hold(app.UIAxes, 'on')
            

            tmp = visboundaries(app.UIAxes,object,'Color',app.colors{color_num},'EnhanceVisibility',0,'LineWidth',app.contour_line_width);
            app.contours=[app.contours,tmp];
            
            hold(app.UIAxes, 'off')
            drawnow;
        end
        function make_label_img(app)
            
            app.label = bwlabel(app.splitmask>0,8);
            
            [Y,X] = meshgrid(1:size(app.label,2),1:size(app.label,1));
            app.positions_X = X(app.label>0);
            app.positions_Y = Y(app.label>0);
            app.labels = app.label(app.label>0);
        end
        function update_history(app)
            app.history_position = app.history_position+1;
            if app.history_position>app.history_len
                app.history_position = 1;  
                app.show_lines();
            end
            app.history{app.history_position} = app.splitmask;
            
        end
        function load_history(app)
            
            app.splitmask = app.history{app.history_position};
            app.make_label_img()
            app.show_lines();
        end
        function ind = get_nearest_label(app,position)
            D = sqrt((app.positions_X-position(2)).^2 +  (app.positions_Y-position(1)).^2);
            
            [min_value,min_ind] = min(D);
            
            if min_value<300
                ind = app.labels(min_ind);
            else
                ind = 0;
            end
            
        end
        
        
        function join(app)
            app.click_on=0;
            h=drawfreehand(app.UIAxes,'Closed',0);
            app.click_on=1;
            draw_image=h.createMask(app.img);
            delete(h);
            L=app.label;
            u=unique(L(draw_image))';
            u(u==0)=[];
            mask_join=false(size(app.img));
            for k=u
                mask_join(L==k)=1;
            end
            mask_join=imclose(mask_join,ones(3));
            
            app.splitmask(mask_join)=1;
            app.make_label_img
            app.show_lines()
            app.update_history()
            
        end
        
        
        function split(app)
            app.click_on=0;
            h=drawfreehand(app.UIAxes,'Closed',0);
            app.click_on=1;
            draw_image=h.createMask(app.img);
%             P00 = h.getPosition;
            delete(h);
            
            [x,y]=find(draw_image);
            P00=[y,x];
            
            F = false(size(draw_image));

            % P0 = unique(round(P00),'rows');
            P00=P00+(rand(size(P00))-0.6);
            [~,ia1,~]=unique(round(P00(:,1)*10)/10,'rows');
            [~,ia2,~]=unique(round(P00(:,2)*10)/10,'rows');
            vektor=zeros(1,size(P00,1));
            vektor(ia1)=vektor(ia1)+1;
            vektor(ia2)=vektor(ia2)+2;
            P0=P00(find(vektor>0),:);
            
            D = round([0; cumsum(sum(abs(diff(P0)),2))]);
            [D,ia1,~]=unique(D,'rows');
            P0=P0(ia1,:);
            P = interp1(D,P0,D(1):.3:D(end)); % ...to close the gaps
            
%             P = unique(round(P),'rows');
            P = round(P);
            
            P(P<1)=1;
            P1=P(:,1);
            P2=P(:,2);
            P1(P1>size(draw_image,2))=size(draw_image,2);
            P2(P2>size(draw_image,1))=size(draw_image,1);
            S = sub2ind(size(draw_image),P2,P1);
            
            
            F(S) = true;
            s=zeros(3);
            s(2,2)=1;
            s(1,2)=1;
%             s(2,1)=1;
            F=imdilate(F,s);
            
            
            app.splitmask(F)=0;
            app.make_label_img()
            
            app.show_lines()
            app.update_history()
            
        end
        function save_results(app)
            
            strtime_tmp = replace(replace(datestr(now),' ','_'),':','_'); 
            default_name = [replace(replace(app.img_name,'.JPG',''),'.jpg',' ') '_final_' app.user_name '_' strtime_tmp '.png'];


            imwrite(uint8(app.splitmask*50),[default_name]);
            
            exportapp(app.UIFigure,[default_name(1:end-4) '_example.png'])
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img_name, mask_name, user_name, params)
            app.contour_line_width = 1;
            app.min_len = 80;
            app.history_len=20;
            app.colors = {'m','r','b'};
            
            app.dilatation = params.dilatation;
            app.resize = params.resize;
            app.min_len = params.min_len;
             
            app.click_on = 1;
            app.contours = [];
            app.tmp_img = [];
            
            
            app.user_name = user_name;
            app.img_name = img_name;
            app.mask_name = mask_name;
            app.img = im2double(imread(img_name));
            tmp = double(imread(mask_name));
            
            app.mask = tmp>max(tmp(:))/2;
            if length(size(app.mask))==3
                app.mask = app.mask(:,:,1);
            end
            
            app.img = imresize(app.img,app.resize);
            app.mask = imdilate(app.mask,strel('disk',1));
            app.mask = imresize(app.mask,app.resize,'nearest');
            app.min_len = round(app.min_len/2);
            
            
            app.splitmask = split_mask(app.mask,app.min_len,app.dilatation);
            app.make_label_img()
            
            
            app.ButtonGroupSelectionChanged( 'init')
            
                 
            app.history = {};
            for k = 1:app.history_len
                app.history{k} = app.splitmask;
            end
            app.history_position = 1;
            
            app.show_lines()
            
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            [~,tmp,~] = fileparts(img_name);
            tmp = replace(tmp,'_','\_');
            title(app.UIAxes,tmp)
            
            
%             app.UIAxes.Toolbar.Visible = 'on'; 
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
            if app.click_on==0
                return
            end

            try 
                if isequal(event.Source.CurrentObject.Parent,app.UIAxes)  
                    object_x = event.Source.CurrentObject.Parent;
                else
                    if isequal(event.Source.CurrentObject.Parent.Parent,app.UIAxes)
                        object_x = event.Source.CurrentObject.Parent.Parent;
                    else
                        return
                    end
                end
            catch
                return
            end
            
                
             position=object_x.CurrentPoint;
             position=[position(1,1),position(1,2)];
             
             
             tmp = app.get_nearest_label(position);
             if tmp==0
                 return
             end
             object = app.label==tmp;


             if strcmp(app.UIFigure.SelectionType,'normal')
                 tmp =2;
             elseif strcmp(app.UIFigure.SelectionType,'alt')
                 tmp = 3;
             elseif strcmp(app.UIFigure.SelectionType,'extend')
                 tmp = 1;
             end
             app.splitmask(object) = tmp;
             app.add_line(object,tmp);
             app.update_history()
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            strtime_tmp = replace(replace(datestr(now),' ','_'),':','_'); 
            default_name = [replace(replace(app.img_name,'.JPG',''),'.jpg',' ') '_' app.user_name '_' strtime_tmp '.png'];
            
            
            [file,path] = uiputfile('.tif','Save results', default_name);
            
            imwrite(uint8(app.splitmask*50),[path filesep file]);
            
            exportapp(app.UIFigure,[path filesep file(1:end-4) '_example.png'])
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            [file,path] = uigetfile('.png','Load results');
            
            app.splitmask = double(imread([path filesep file]))/50;
            app.make_label_img()

            app.show_img()
            app.show_lines()

        end

        % Button pushed function: undozButton
        function undozButtonPushed(app, event)
            app.history_position = app.history_position -1;
            if app.history_position==0
                app.history_position = app.history_len;
            end
            app.load_history()
        end

        % Button pushed function: redoButton
        function redoButtonPushed(app, event)
            app.history_position = app.history_position + 1;
            if app.history_position>app.history_len
                app.history_position = 1;
            end
            app.load_history()
        end

        % Value changed function: showlinesctrlCheckBox
        function showlinesctrlCheckBoxValueChanged(app, event)
            value = app.showlinesctrlCheckBox.Value;
            
            if value
                delete(app.tmp_img)
            else
                hold(app.UIAxes, 'on')
                app.tmp_img = imshow(app.img2show,'Parent',app.UIAxes);
                hold(app.UIAxes, 'off')
            end
            
        end

        % Button pushed function: splitsButton
        function splitsButtonPushed(app, event)
            app.split()
        end

        % Button pushed function: joinjButton
        function joinjButtonPushed(app, event)
            
            
            app.join()
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            
            
%             disp('figure key press')
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;

            if isequal(selectedButton,app.original1Button)
               expression = app.EditField_original.Value;
            elseif isequal(selectedButton,app.equalized2Button)
               expression = app.EditField_eq.Value;
            elseif isequal(selectedButton,app.equalizedG3Button)
               expression = app.EditField_eqG.Value;
            elseif isequal(selectedButton,app.equalizedGB4Button)
               expression = app.EditField_eqGB.Value;
            elseif isequal(selectedButton,app.custom5Button)
               expression = app.EditField_custom.Value;
            end
            
            
            x = app.img;
                
            eval(expression)
            
            app.img2show = y;
            
            
            app.show_img()
            app.show_lines()
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.save_results()
            app.delete()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.save_results()
            app.delete()
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            disp(key)
            if strcmp(key,'j')
                app.join()
            end
            if strcmp(key,'s')
                app.split()
            end
            if strcmp(key,'z')
                app.undozButtonPushed('shortcut')
            end
            if strcmp(key,'control')
                app.showlinesctrlCheckBox.Value = ~app.showlinesctrlCheckBox.Value;
                app.showlinesctrlCheckBoxValueChanged('shortcut')
            end
            if strcmp(key,'numpad1')
                app.original1Button.Value = 1;
                app.ButtonGroupSelectionChanged('shortcut')
            end
            if strcmp(key,'numpad2')
                app.equalized2Button.Value = 1;
                app.ButtonGroupSelectionChanged('shortcut')
            end
            if strcmp(key,'numpad3')
                app.equalizedG3Button.Value = 1;
                app.ButtonGroupSelectionChanged('shortcut')
            end
            if strcmp(key,'numpad4')
                app.equalizedGB4Button.Value = 1;
                app.ButtonGroupSelectionChanged('shortcut')
            end
            if strcmp(key,'numpad5')
                app.custom5Button.Value = 1;
                app.ButtonGroupSelectionChanged('shortcut')
            end
            if strcmp(key,'y')
                zoom(app.UIAxes,'on');
            end
            
            if strcmp(key,'x')
                zoom(app.UIAxes,'off');
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
            end


        end

        % Button pushed function: zoomoffButton
        function zoomoffButtonPushed(app, event)
            zoom(app.UIAxes,'off');
        end

        % Button pushed function: zoomonyButton
        function zoomonyButtonPushed(app, event)
            zoom(app.UIAxes,'on');
        end

        % Button pushed function: zoomresetxButton
        function zoomresetxButtonPushed(app, event)
            zoom(app.UIAxes,'off');
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.showlinesctrlCheckBox = uicheckbox(app.UIFigure);
            app.showlinesctrlCheckBox.ValueChangedFcn = createCallbackFcn(app, @showlinesctrlCheckBoxValueChanged, true);
            app.showlinesctrlCheckBox.Text = 'show lines (ctrl)';
            app.showlinesctrlCheckBox.Value = true;
            app.undozButton = uibutton(app.UIFigure, 'push');
            app.undozButton.ButtonPushedFcn = createCallbackFcn(app, @undozButtonPushed, true);
            app.undozButton.Text = 'undo (z)';
            app.redoButton = uibutton(app.UIFigure, 'push');
            app.redoButton.ButtonPushedFcn = createCallbackFcn(app, @redoButtonPushed, true);
            app.redoButton.Text = 'redo';
            app.splitsButton = uibutton(app.UIFigure, 'push');
            app.splitsButton.ButtonPushedFcn = createCallbackFcn(app, @splitsButtonPushed, true);
            app.splitsButton.Text = 'split (s)';
            app.joinjButton = uibutton(app.UIFigure, 'push');
            app.joinjButton.ButtonPushedFcn = createCallbackFcn(app, @joinjButtonPushed, true);
            app.joinjButton.Text = 'join (j)';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.original1Button = uiradiobutton(app.ButtonGroup);
            app.original1Button.Text = 'original (1)';
            app.original1Button.Value = true;
            app.equalized2Button = uiradiobutton(app.ButtonGroup);
            app.equalized2Button.Text = 'equalized (2)';
            app.equalizedG3Button = uiradiobutton(app.ButtonGroup);
            app.equalizedG3Button.Text = 'equalized G (3)';
            app.equalizedGB4Button = uiradiobutton(app.ButtonGroup);
            app.equalizedGB4Button.Text = 'equalized G + B (4)';
            app.custom5Button = uiradiobutton(app.ButtonGroup);
            app.custom5Button.Text = 'custom (5)';
            app.EditField_original = uieditfield(app.ButtonGroup, 'text');
            app.EditField_original.Editable = 'off';
            app.EditField_original.Value = 'y = x;';
            app.EditField_eq = uieditfield(app.ButtonGroup, 'text');
            app.EditField_eq.Editable = 'off';
            app.EditField_eq.Value = 'for k = 1:3; y(:,:,k) =  adapthisteq(x(:,:,k));end;';
            app.EditField_eqG = uieditfield(app.ButtonGroup, 'text');
            app.EditField_eqG.Editable = 'off';
            app.EditField_eqG.Value = 'y = adapthisteq(x(:,:,2));';
            app.EditField_eqGB = uieditfield(app.ButtonGroup, 'text');
            app.EditField_eqGB.Editable = 'off';
            app.EditField_eqGB.Value = 'y =  adapthisteq((x(:,:,2)+x(:,:,3))/2);';
            app.EditField_custom = uieditfield(app.ButtonGroup, 'text');
            app.EditField_custom.Value = 'y = x;';
            app.zoomoffButton = uibutton(app.ButtonGroup, 'push');
            app.zoomoffButton.ButtonPushedFcn = createCallbackFcn(app, @zoomoffButtonPushed, true);
            app.zoomoffButton.Text = 'zoom off';
            app.zoomonyButton = uibutton(app.ButtonGroup, 'push');
            app.zoomonyButton.ButtonPushedFcn = createCallbackFcn(app, @zoomonyButtonPushed, true);
            app.zoomonyButton.Text = 'zoom on (y)';
            app.zoomresetxButton = uibutton(app.ButtonGroup, 'push');
            app.zoomresetxButton.ButtonPushedFcn = createCallbackFcn(app, @zoomresetxButtonPushed, true);
            app.zoomresetxButton.Text = 'zoom reset (x)';
            app.leftclickLabel = uilabel(app.UIFigure);
            app.leftclickLabel.Text = 'left click           ';
            app.shiftclickLabel = uilabel(app.UIFigure);
            app.shiftclickLabel.Text = 'shift click';
            app.rightclickLabel = uilabel(app.UIFigure);
            app.rightclickLabel.Text = ' right click';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = artery_vein_labeler(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end