classdef two_colors_lines < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        showlinesyCheckBox           matlab.ui.control.CheckBox
        TgreensegSpinner             matlab.ui.control.Spinner
        TgreensegSpinnerLabel        matlab.ui.control.Label
        showmasksyCheckBox           matlab.ui.control.CheckBox
        drawGButton                  matlab.ui.control.Button
        drawRButton                  matlab.ui.control.Button
        deleteButton_2               matlab.ui.control.Button
        deleteButton                 matlab.ui.control.Button
        pTRSpinner                   matlab.ui.control.Spinner
        pTRSpinnerLabel              matlab.ui.control.Label
        pTGSpinner                   matlab.ui.control.Spinner
        pTGSpinnerLabel              matlab.ui.control.Label
        TredsegSpinner               matlab.ui.control.Spinner
        TredsegSpinnerLabel          matlab.ui.control.Label
        saveButton                   matlab.ui.control.Button
        savedLabel                   matlab.ui.control.Label
        loaded_or_initLabel          matlab.ui.control.Label
        RLabel_2                     matlab.ui.control.Label
        BLabel_2                     matlab.ui.control.Label
        GLabel_2                     matlab.ui.control.Label
        initpercSpinner_maxR         matlab.ui.control.Spinner
        initpercSpinner_minR         matlab.ui.control.Spinner
        initpercSpinner_maxG         matlab.ui.control.Spinner
        initpercSpinner_minG         matlab.ui.control.Spinner
        showRGBxCheckBox             matlab.ui.control.CheckBox
        showoriginalleftaltCheckBox  matlab.ui.control.CheckBox
        newpointcontbCheckBox        matlab.ui.control.CheckBox
        initpercSpinner_maxB         matlab.ui.control.Spinner
        stepGSpinner                 matlab.ui.control.Spinner
        stepSpinnerLabel_2           matlab.ui.control.Label
        stepRSpinner                 matlab.ui.control.Spinner
        stepSpinnerLabel             matlab.ui.control.Label
        newpointButton_G             matlab.ui.control.Button
        clearfolderButton            matlab.ui.control.Button
        loadsettingsButton           matlab.ui.control.Button
        savesettingsButton           matlab.ui.control.Button
        resetButton                  matlab.ui.control.Button
        editpointsvCheckBox          matlab.ui.control.CheckBox
        TSpinner_init                matlab.ui.control.Spinner
        TgreenSpinner                matlab.ui.control.Spinner
        TgreenSpinnerLabel           matlab.ui.control.Label
        TredSpinner                  matlab.ui.control.Spinner
        TredSpinnerLabel             matlab.ui.control.Label
        initpercSpinner_minB         matlab.ui.control.Spinner
        initpercSpinnerLabel         matlab.ui.control.Label
        tSpinner_init                matlab.ui.control.Spinner
        hSpinner_init                matlab.ui.control.Spinner
        dSpinner_init                matlab.ui.control.Spinner
        filenumEditField             matlab.ui.control.NumericEditField
        newpointButton_R             matlab.ui.control.Button
        filenameLabel                matlab.ui.control.Label
        showpointscCheckBox          matlab.ui.control.CheckBox
        numfilesLabel                matlab.ui.control.Label
        GtSpinner                    matlab.ui.control.Spinner
        GtSpinnerLabel               matlab.ui.control.Label
        GhSpinner                    matlab.ui.control.Spinner
        GhSpinnerLabel               matlab.ui.control.Label
        GdSpinner                    matlab.ui.control.Spinner
        GdSpinnerLabel               matlab.ui.control.Label
        RtSpinner                    matlab.ui.control.Spinner
        RtSpinnerLabel               matlab.ui.control.Label
        RhSpinner                    matlab.ui.control.Spinner
        RhSpinnerLabel               matlab.ui.control.Label
        RdSpinner                    matlab.ui.control.Spinner
        RdSpinnerLabel               matlab.ui.control.Label
        Button_next                  matlab.ui.control.Button
        Button_prev                  matlab.ui.control.Button
        BLabel                       matlab.ui.control.Label
        Spinner_Bmax                 matlab.ui.control.Spinner
        Spinner_Bmin                 matlab.ui.control.Spinner
        GLabel                       matlab.ui.control.Label
        Spinner_Gmax                 matlab.ui.control.Spinner
        Spinner_Gmin                 matlab.ui.control.Spinner
        RLabel                       matlab.ui.control.Label
        Spinner_Rmax                 matlab.ui.control.Spinner
        Spinner_Rmin                 matlab.ui.control.Spinner
        UIAxes_Rmain                 matlab.ui.control.UIAxes
        UIAxes_Gmain                 matlab.ui.control.UIAxes
        UIAxes_Gdown                 matlab.ui.control.UIAxes
        UIAxes_Rdown                 matlab.ui.control.UIAxes
        UIAxes_Rleft                 matlab.ui.control.UIAxes
        UIAxes_Gright                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        files
        data
        data_orig
        points_R_all
        points_G_all
        points_values_R_all
        points_values_G_all
        
        large_point_R
        large_point_G
        
        
        points_R 
        points_G
        points_values_R 
        points_values_G
        binaryResuslts_R
        binaryResuslts_G
        
        points_R_loaded
        points_G_loaded
        points_values_R_loaded
        points_values_G_loaded
        binaryResuslts_R_loaded
        binaryResuslts_G_loaded
        
        click_on
        
        rImg_main
        gImg_main
        bImg_main
        
        rImg_down
        gImg_down
        bImg_down
        
        rImg_right
        gImg_right
        bImg_right
        
        
        rImg_left
        gImg_left
        bImg_left
        
        
        rImg_main_orig
        gImg_main_orig
        bImg_main_orig
        
        rImg_down_orig
        gImg_down_orig
        bImg_down_orig
        
        rImg_right_orig
        gImg_right_orig
        bImg_right_orig
        
        rImg_left_orig
        gImg_left_orig
        bImg_left_orig
        
        
        img_main_r
        img_main_g
        img_down_r
        img_down_g
        img_left_r
        img_right_g


        points_main_r_1
        points_main_g_1
        points_down_r_1
        points_down_g_1
        points_left_r_1
        points_right_g_1
        
        points_main_r_2
        points_main_g_2
        points_down_r_2
        points_down_g_2
        points_left_r_2
        points_right_g_2
        
        
        points_main_r_3
        points_main_g_3
        points_down_r_3
        points_down_g_3
        points_left_r_3
        points_right_g_3
        
        points_main_r_4
        points_main_g_4
        points_down_r_4
        points_down_g_4
        points_left_r_4
        points_right_g_4
        
        points_main_r_5
        points_main_g_5
        points_down_r_5
        points_down_g_5
        points_left_r_5
        points_right_g_5
        
        
        selected_point
        selected_point_ind
        selected_point_axes
        resize_factor


        segm_R_main
        segm_G_main
        segm_R_left
        segm_G_right
        segm_R_down
        segm_G_down

        contours_main_r
        contours_main_g

        contours_left_r
        contours_right_g

        contours_down_r
        contours_down_g

        lines_r
        lines_g

        lines_r_plots
        lines_g_plots

        lines_r_loaded
        lines_g_loaded

        TgreensegSpinner_loaded
        TredsegSpinner_loaded
    end
    
    methods (Access = public)
        
        function reload_file(app)
            app.data = load(app.files{app.filenumEditField.Value});
            
%             tmp1 = app.data;

            app.data_orig = load(replace(app.files{app.filenumEditField.Value},'\cell','\cell_orig'));

%             tmp2 = app.data_orig;
            
            app.filenameLabel.Text = app.files{app.filenumEditField.Value};
            
        end
        
        function show_img_R(app)
            
            if app.showoriginalleftaltCheckBox.Value
                tmp = app.data_orig;
            else
                tmp = app.data;
            end
                 
            hold(app.UIAxes_Rmain, 'off')
            if app.showRGBxCheckBox.Value
                color=cat(3,...
                    mat2gray(tmp.rImg_main,[app.Spinner_Rmin.Value   app.Spinner_Rmax.Value]),...
                    mat2gray(tmp.gImg_main,[app.Spinner_Gmin.Value   app.Spinner_Gmax.Value]),...
                    mat2gray(tmp.bImg_main,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            else
                color=cat(3,...
                    mat2gray(tmp.rImg_main,[app.Spinner_Rmin.Value   app.Spinner_Rmax.Value]),...
                    zeros(size(tmp.rImg_main)),...
                    mat2gray(tmp.bImg_main,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            end
            app.img_main_r=imshow(color,'Parent',app.UIAxes_Rmain);
            title(app.UIAxes_Rmain,'')
            ylabel(app.UIAxes_Rmain,'')
            xlabel(app.UIAxes_Rmain,'')
            
  
            hold(app.UIAxes_Rdown, 'off')
            if app.showRGBxCheckBox.Value
                color=cat(3,...
                    mat2gray(tmp.rImg_down,[app.Spinner_Rmin.Value   app.Spinner_Rmax.Value]),...
                    mat2gray(tmp.gImg_down,[app.Spinner_Gmin.Value   app.Spinner_Gmax.Value]),...
                    mat2gray(tmp.bImg_down,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            else
                color=cat(3,...
                    mat2gray(tmp.rImg_down,[app.Spinner_Rmin.Value   app.Spinner_Rmax.Value]),...
                    zeros(size(tmp.rImg_down)),...
                    mat2gray(tmp.bImg_down,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            end
            app.img_down_r=imshow(color,'Parent',app.UIAxes_Rdown);
            title(app.UIAxes_Rdown,'')
            ylabel(app.UIAxes_Rdown,'')
            xlabel(app.UIAxes_Rdown,'')
            
            hold(app.UIAxes_Rleft, 'off')
            if app.showRGBxCheckBox.Value
                color=cat(3,...
                    mat2gray(tmp.rImg_left,[app.Spinner_Rmin.Value   app.Spinner_Rmax.Value]),...
                    fliplr(mat2gray(tmp.gImg_right,[app.Spinner_Gmin.Value   app.Spinner_Gmax.Value])),...
                    mat2gray(tmp.bImg_left,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            else
                color=cat(3,...
                    mat2gray(tmp.rImg_left,[app.Spinner_Rmin.Value   app.Spinner_Rmax.Value]),...
                    zeros(size(tmp.rImg_left)),...
                    mat2gray(tmp.bImg_left,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            end
            app.img_left_r=imshow(color,'Parent',app.UIAxes_Rleft);
            title(app.UIAxes_Rleft,'')
            ylabel(app.UIAxes_Rleft,'')
            xlabel(app.UIAxes_Rleft,'')
            drawnow;
        end
        
        function show_img_G(app)
            
            if app.showoriginalleftaltCheckBox.Value
                tmp = app.data_orig;
            else
                tmp = app.data;
            end
            
            hold(app.UIAxes_Gmain, 'off')
            if app.showRGBxCheckBox.Value
                color=cat(3,...
                    mat2gray(tmp.rImg_main,[app.Spinner_Rmin.Value   app.Spinner_Rmax.Value]),...
                    mat2gray(tmp.gImg_main,[app.Spinner_Gmin.Value   app.Spinner_Gmax.Value]),...
                    mat2gray(tmp.bImg_main,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            else
                color=cat(3,...
                    zeros(size(tmp.gImg_main)),...
                    mat2gray(tmp.gImg_main,[app.Spinner_Gmin.Value   app.Spinner_Gmax.Value]),...
                    mat2gray(tmp.bImg_main,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            end
            app.img_main_g=imshow(color,'Parent',app.UIAxes_Gmain);
            title(app.UIAxes_Gmain,'')
            ylabel(app.UIAxes_Gmain,'')
            xlabel(app.UIAxes_Gmain,'')
            
            
            hold(app.UIAxes_Gdown, 'off')
            if app.showRGBxCheckBox.Value
                color=cat(3,...
                    mat2gray(tmp.rImg_down,[app.Spinner_Rmin.Value   app.Spinner_Rmax.Value]),...
                    mat2gray(tmp.gImg_down,[app.Spinner_Gmin.Value   app.Spinner_Gmax.Value]),...
                    mat2gray(tmp.bImg_down,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            else
                color=cat(3,...
                    zeros(size(tmp.gImg_down)),...
                    mat2gray(tmp.gImg_down,[app.Spinner_Gmin.Value   app.Spinner_Gmax.Value]),...
                    mat2gray(tmp.bImg_down,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            end
            app.img_down_g=imshow(color,'Parent',app.UIAxes_Gdown);
            title(app.UIAxes_Gdown,'')
            ylabel(app.UIAxes_Gdown,'')
            xlabel(app.UIAxes_Gdown,'')
            
            hold(app.UIAxes_Gright, 'off')
            if app.showRGBxCheckBox.Value
                color=cat(3,...
                    fliplr(mat2gray(tmp.rImg_left,[app.Spinner_Rmin.Value   app.Spinner_Rmax.Value])),...
                    mat2gray(tmp.gImg_right,[app.Spinner_Gmin.Value   app.Spinner_Gmax.Value]),...
                    mat2gray(tmp.bImg_right,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            else
                color=cat(3,...
                    zeros(size(tmp.gImg_right)),...
                    mat2gray(tmp.gImg_right,[app.Spinner_Gmin.Value   app.Spinner_Gmax.Value]),...
                    mat2gray(tmp.bImg_right,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            end
            app.img_right_g=imshow(color,'Parent',app.UIAxes_Gright);
            title(app.UIAxes_Gright,'')
            ylabel(app.UIAxes_Gright,'')
            xlabel(app.UIAxes_Gright,'')
            drawnow;
        end  
        
        function show_imgs(app)
            app.show_img_R()
            app.show_img_G()
        end
        
        function show_points(app)
            app.show_points_R()
            app.show_points_G()
        end
        
        
        function show_points_R(app)
            app.binaryResuslts_R = app.binaryResuslts_R>0;
            
            
            hold(app.UIAxes_Rmain, 'on')
            if ~isempty(app.points_main_r_1)
                delete(app.points_main_r_1);delete(app.points_main_r_2);delete(app.points_main_r_3);
            end
            if ~isempty(app.points_main_r_4)
                delete(app.points_main_r_4);
            end
            if ~isempty(app.points_main_r_5)
                delete(app.points_main_r_5);
            end
            if ~isempty(app.points_R)
                if app.showpointscCheckBox.Value==1
                    app.points_main_r_1=plot(app.UIAxes_Rmain,app.points_R(:,1), app.points_R(:,2), 'b*','MarkerSize',3);
                    app.points_main_r_2=plot(app.UIAxes_Rmain,app.points_R(app.binaryResuslts_R,1), app.points_R(app.binaryResuslts_R,2), 'ro','MarkerSize',3);
                    app.points_main_r_3=plot(app.UIAxes_Rmain,app.points_R(app.binaryResuslts_R,1), app.points_R(app.binaryResuslts_R,2), 'g*','MarkerSize',3);
                    if ~isempty(app.large_point_R)
                        if ~app.binaryResuslts_R(app.large_point_R)
                            app.points_main_r_4=plot(app.UIAxes_Rmain,app.points_R(app.large_point_R,1), app.points_R(app.large_point_R,2), 'b*','MarkerSize',6);
                        else
                            app.points_main_r_4=plot(app.UIAxes_Rmain,app.points_R(app.large_point_R,1), app.points_R(app.large_point_R,2), 'ro','MarkerSize',6);
                            app.points_main_r_5=plot(app.UIAxes_Rmain,app.points_R(app.large_point_R,1), app.points_R(app.large_point_R,2), 'g*','MarkerSize',6);
                        end
                    end
                end
            end
            

            hold(app.UIAxes_Rdown, 'on')
            if ~isempty(app.points_down_r_1)
                delete(app.points_down_r_1);delete(app.points_down_r_2);delete(app.points_down_r_3);
            end
            if ~isempty(app.points_down_r_4)
                delete(app.points_down_r_4);
            end
            if ~isempty(app.points_down_r_5)
                delete(app.points_down_r_5);
            end
            if ~isempty(app.points_R)
                if app.showpointscCheckBox.Value==1
                    app.points_down_r_1=plot(app.UIAxes_Rdown,app.points_R(:,1), app.points_R(:,3)*app.resize_factor, 'b*','MarkerSize',3);
                    app.points_down_r_2=plot(app.UIAxes_Rdown,app.points_R(app.binaryResuslts_R,1), app.points_R(app.binaryResuslts_R,3)*app.resize_factor, 'ro','MarkerSize',3);
                    app.points_down_r_3=plot(app.UIAxes_Rdown,app.points_R(app.binaryResuslts_R,1), app.points_R(app.binaryResuslts_R,3)*app.resize_factor, 'g*','MarkerSize',3);
                    if ~isempty(app.large_point_R)
                        if ~app.binaryResuslts_R(app.large_point_R)
                            app.points_down_r_4=plot(app.UIAxes_Rdown,app.points_R(app.large_point_R,1), app.points_R(app.large_point_R,3)*app.resize_factor, 'b*','MarkerSize',6);
                        else
                            app.points_down_r_4=plot(app.UIAxes_Rdown,app.points_R(app.large_point_R,1), app.points_R(app.large_point_R,3)*app.resize_factor, 'ro','MarkerSize',6);
                            app.points_down_r_5=plot(app.UIAxes_Rdown,app.points_R(app.large_point_R,1), app.points_R(app.large_point_R,3)*app.resize_factor, 'g*','MarkerSize',6);
                        end
                    end
                
                end
            end
            
            
            hold(app.UIAxes_Rleft, 'on')
            if ~isempty(app.points_left_r_1)
                delete(app.points_left_r_1);delete(app.points_left_r_2);delete(app.points_left_r_3);
            end
            if ~isempty(app.points_left_r_4)
                delete(app.points_left_r_4);
            end
            if ~isempty(app.points_left_r_5)
                delete(app.points_left_r_5);
            end
            if ~isempty(app.points_R)
                if app.showpointscCheckBox.Value==1
                    app.points_left_r_1=plot(app.UIAxes_Rleft,app.points_R(:,3)*app.resize_factor, app.points_R(:,2), 'b*','MarkerSize',3);
                    app.points_left_r_2=plot(app.UIAxes_Rleft,app.points_R(app.binaryResuslts_R,3)*app.resize_factor, app.points_R(app.binaryResuslts_R,2), 'ro','MarkerSize',3);
                    app.points_left_r_3=plot(app.UIAxes_Rleft,app.points_R(app.binaryResuslts_R,3)*app.resize_factor, app.points_R(app.binaryResuslts_R,2), 'g*','MarkerSize',3);
                    if ~isempty(app.large_point_R)
                        if ~app.binaryResuslts_R(app.large_point_R)
                            app.points_left_r_4=plot(app.UIAxes_Rleft,app.points_R(app.large_point_R,3)*app.resize_factor, app.points_R(app.large_point_R,2), 'b*','MarkerSize',6);
                        else
                            app.points_left_r_4=plot(app.UIAxes_Rleft,app.points_R(app.large_point_R,3)*app.resize_factor, app.points_R(app.large_point_R,2), 'ro','MarkerSize',6);
                            app.points_left_r_5=plot(app.UIAxes_Rleft,app.points_R(app.large_point_R,3)*app.resize_factor, app.points_R(app.large_point_R,2), 'g*','MarkerSize',6);
                        end
                    end
                
                end
            end


            app.large_point_R = [];
            
            drawnow;
        end
        
        
        function show_points_G(app)
            
            app.binaryResuslts_G = app.binaryResuslts_G>0;
            
            hold(app.UIAxes_Gmain, 'on')
            if ~isempty(app.points_main_g_1)
                delete(app.points_main_g_1);delete(app.points_main_g_2);delete(app.points_main_g_3);
            end
            if ~isempty(app.points_main_g_4)
                delete(app.points_main_g_4);
            end
            if ~isempty(app.points_main_g_5)
                delete(app.points_main_g_5);
            end
            if ~isempty(app.points_G)
                if app.showpointscCheckBox.Value==1
                    app.points_main_g_1=plot(app.UIAxes_Gmain,app.points_G(:,1), app.points_G(:,2), 'b*','MarkerSize',3);
                    app.points_main_g_2=plot(app.UIAxes_Gmain,app.points_G(app.binaryResuslts_G,1), app.points_G(app.binaryResuslts_G,2), 'go','MarkerSize',3);
                    app.points_main_g_3=plot(app.UIAxes_Gmain,app.points_G(app.binaryResuslts_G,1), app.points_G(app.binaryResuslts_G,2), 'r*','MarkerSize',3);
                    if ~isempty(app.large_point_G)
                        if ~app.binaryResuslts_G(app.large_point_G)
                            app.points_main_g_4=plot(app.UIAxes_Gmain,app.points_G(app.large_point_G,1), app.points_G(app.large_point_G,2), 'b*','MarkerSize',6);
                        else
                            app.points_main_g_4=plot(app.UIAxes_Gmain,app.points_G(app.large_point_G,1), app.points_G(app.large_point_G,2), 'go','MarkerSize',6);
                            app.points_main_g_5=plot(app.UIAxes_Gmain,app.points_G(app.large_point_G,1), app.points_G(app.large_point_G,2), 'r*','MarkerSize',6);
                        end
                    end
                    
                end
            end
            

            hold(app.UIAxes_Gdown, 'on')
            if ~isempty(app.points_down_g_1)
                delete(app.points_down_g_1);delete(app.points_down_g_2);delete(app.points_down_g_3);
            end
            if ~isempty(app.points_down_g_4)
                delete(app.points_down_g_4);
            end
            if ~isempty(app.points_down_g_5)
                delete(app.points_down_g_5);
            end
            if ~isempty(app.points_G)
                if app.showpointscCheckBox.Value==1
                    app.points_down_g_1=plot(app.UIAxes_Gdown,app.points_G(:,1), app.points_G(:,3)*app.resize_factor, 'b*','MarkerSize',3);
                    app.points_down_g_2=plot(app.UIAxes_Gdown,app.points_G(app.binaryResuslts_G,1), app.points_G(app.binaryResuslts_G,3)*app.resize_factor, 'go','MarkerSize',3);
                    app.points_down_g_3=plot(app.UIAxes_Gdown,app.points_G(app.binaryResuslts_G,1), app.points_G(app.binaryResuslts_G,3)*app.resize_factor, 'r*','MarkerSize',3);
                    if ~isempty(app.large_point_G)
                        if ~app.binaryResuslts_G(app.large_point_G)
                            app.points_down_g_4=plot(app.UIAxes_Gdown,app.points_G(app.large_point_G,1), app.points_G(app.large_point_G,3)*app.resize_factor, 'b*','MarkerSize',6);
                        else
                            app.points_down_g_4=plot(app.UIAxes_Gdown,app.points_G(app.large_point_G,1), app.points_G(app.large_point_G,3)*app.resize_factor, 'go','MarkerSize',6);
                            app.points_down_g_5=plot(app.UIAxes_Gdown,app.points_G(app.large_point_G,1), app.points_G(app.large_point_G,3)*app.resize_factor, 'r*','MarkerSize',6);
                        end
                    end
                
                end
            end
            
            hold(app.UIAxes_Gright, 'on')
            if ~isempty(app.points_right_g_1)
                delete(app.points_right_g_1);delete(app.points_right_g_2);delete(app.points_right_g_3);
            end
            if ~isempty(app.points_right_g_4)
                delete(app.points_right_g_4);
            end
            if ~isempty(app.points_right_g_5)
                delete(app.points_right_g_5);
            end
            tmp = size(app.data.img_crop,3)*app.resize_factor;
            if ~isempty(app.points_G)
                if app.showpointscCheckBox.Value==1
                    app.points_right_g_1=plot(app.UIAxes_Gright,tmp-app.points_G(:,3)*app.resize_factor, app.points_G(:,2), 'b*','MarkerSize',3);
                    app.points_right_g_2=plot(app.UIAxes_Gright,tmp-app.points_G(app.binaryResuslts_G,3)*app.resize_factor, app.points_G(app.binaryResuslts_G,2), 'go','MarkerSize',3);
                    app.points_right_g_3=plot(app.UIAxes_Gright,tmp-app.points_G(app.binaryResuslts_G,3)*app.resize_factor, app.points_G(app.binaryResuslts_G,2), 'r*','MarkerSize',3);  
                    if ~isempty(app.large_point_G)
                        if ~app.binaryResuslts_G(app.large_point_G)
                            app.points_right_g_4=plot(app.UIAxes_Gright,tmp-app.points_G(app.large_point_G,3)*app.resize_factor, app.points_G(app.large_point_G,2), 'b*','MarkerSize',6);
                        else
                            app.points_right_g_4=plot(app.UIAxes_Gright,tmp-app.points_G(app.large_point_G,3)*app.resize_factor, app.points_G(app.large_point_G,2), 'go','MarkerSize',6);
                            app.points_right_g_5=plot(app.UIAxes_Gright,tmp-app.points_G(app.large_point_G,3)*app.resize_factor, app.points_G(app.large_point_G,2), 'r*','MarkerSize',6);
                        end
                    end
                    
                end
            end
            
            app.large_point_G = [];
            drawnow;
        end


        function get_segmentations_R(app)
            [app.points_R_all,app.points_values_R_all] = robust_3d_maxima_detector(...
                app.data.img_crop(:,:,:,1),app.data.mask_crop,[app.RdSpinner.Value app.RdSpinner.Value ceil(app.RdSpinner.Value/3)],...
                app.RhSpinner.Value);
            
            use = app.points_values_R_all>app.RtSpinner.Value;
            app.points_values_R = app.points_values_R_all(use);
            app.points_R = app.points_R_all(use,:);
            
            app.binaryResuslts_R = app.points_values_R>app.TredSpinner.Value;
            
        end
        function get_segmentations_G(app)
            [app.points_G_all,app.points_values_G_all] = robust_3d_maxima_detector(...
                app.data.img_crop(:,:,:,2),app.data.mask_crop,[app.GdSpinner.Value app.GdSpinner.Value ceil(app.GdSpinner.Value/3)],...
                app.GhSpinner.Value);
            
            use = app.points_values_G_all>app.GtSpinner.Value;
            app.points_values_G = app.points_values_G_all(use);
            app.points_G = app.points_G_all(use,:);
            
            app.binaryResuslts_G = app.points_values_G>app.TgreenSpinner.Value;   
        end
        
        
        function get_segmentations(app)
            app.get_segmentations_R()
            app.get_segmentations_G()
        end
        
        function init_show_ranges(app)
            
            app.Spinner_Rmin.Value =  double(prctile(app.data.rImg_main,app.initpercSpinner_minR.Value,'all'));
            app.Spinner_Rmax.Value =  double(prctile(app.data.rImg_main,100-app.initpercSpinner_maxR.Value,'all'));
            
            app.Spinner_Gmin.Value =  double(prctile(app.data.gImg_main,app.initpercSpinner_minG.Value,'all'));
            app.Spinner_Gmax.Value =  double(prctile(app.data.gImg_main,100-app.initpercSpinner_maxG.Value,'all'));
            
            app.Spinner_Bmin.Value =  double(prctile(app.data.bImg_main,app.initpercSpinner_minB.Value,'all'));
            app.Spinner_Bmax.Value =  double(prctile(app.data.bImg_main,100-app.initpercSpinner_maxB.Value,'all'));
             
            
        end
        
        function resize_projections(app)
            
            
            app.data.rImg_left=imresize(app.data.rImg_left,[size(app.data.rImg_left,1),round(size(app.data.rImg_left,2)*app.resize_factor)]);
            app.data.gImg_left=imresize(app.data.gImg_left,[size(app.data.gImg_left,1),round(size(app.data.gImg_left,2)*app.resize_factor)]);
            app.data.bImg_left=imresize(app.data.bImg_left,[size(app.data.bImg_left,1),round(size(app.data.bImg_left,2)*app.resize_factor)]);
    
            app.data.rImg_right=imresize(app.data.rImg_right,[size(app.data.rImg_right,1),round(size(app.data.rImg_right,2)*app.resize_factor)]);
            app.data.gImg_right=imresize(app.data.gImg_right,[size(app.data.gImg_right,1),round(size(app.data.gImg_right,2)*app.resize_factor)]);
            app.data.bImg_right=imresize(app.data.bImg_right,[size(app.data.bImg_right,1),round(size(app.data.bImg_right,2)*app.resize_factor)]);
    
            app.data.rImg_down=imresize(app.data.rImg_down,[round(size(app.data.rImg_down,1)*app.resize_factor),size(app.data.rImg_down,2)]);
            app.data.gImg_down=imresize(app.data.gImg_down,[round(size(app.data.gImg_down,1)*app.resize_factor),size(app.data.gImg_down,2)]);
            app.data.bImg_down=imresize(app.data.bImg_down,[round(size(app.data.bImg_down,1)*app.resize_factor),size(app.data.bImg_down,2)]);
            
            
            app.data_orig.rImg_left=imresize(app.data_orig.rImg_left_orig,[size(app.data_orig.rImg_left_orig,1),round(size(app.data_orig.rImg_left_orig,2)*app.resize_factor)]);
            app.data_orig.gImg_left=imresize(app.data_orig.gImg_left_orig,[size(app.data_orig.gImg_left_orig,1),round(size(app.data_orig.gImg_left_orig,2)*app.resize_factor)]);
            app.data_orig.bImg_left=imresize(app.data_orig.bImg_left_orig,[size(app.data_orig.bImg_left_orig,1),round(size(app.data_orig.bImg_left_orig,2)*app.resize_factor)]);
    
            app.data_orig.rImg_right=imresize(app.data_orig.rImg_right_orig,[size(app.data_orig.rImg_right_orig,1),round(size(app.data_orig.rImg_right_orig,2)*app.resize_factor)]);
            app.data_orig.gImg_right=imresize(app.data_orig.gImg_right_orig,[size(app.data_orig.gImg_right_orig,1),round(size(app.data_orig.gImg_right_orig,2)*app.resize_factor)]);
            app.data_orig.bImg_right=imresize(app.data_orig.bImg_right_orig,[size(app.data_orig.bImg_right_orig,1),round(size(app.data_orig.bImg_right_orig,2)*app.resize_factor)]);
    
            app.data_orig.rImg_down=imresize(app.data_orig.rImg_down_orig,[round(size(app.data_orig.rImg_down_orig,1)*app.resize_factor),size(app.data_orig.rImg_down_orig,2)]);
            app.data_orig.gImg_down=imresize(app.data_orig.gImg_down_orig,[round(size(app.data_orig.gImg_down_orig,1)*app.resize_factor),size(app.data_orig.gImg_down_orig,2)]);
            app.data_orig.bImg_down=imresize(app.data_orig.bImg_down_orig,[round(size(app.data_orig.bImg_down_orig,1)*app.resize_factor),size(app.data_orig.bImg_down_orig,2)]);
            
            app.data_orig.rImg_main=app.data_orig.rImg_main_orig;
            app.data_orig.gImg_main=app.data_orig.gImg_main_orig;
            app.data_orig.bImg_main=app.data_orig.bImg_main_orig;


        end
        
        
        function init_next(app)
            try
                app.UIFigure.Pointer = 'watch';
                drawnow;
                
                app.reload_file()


                app.init_params()
                
                app.resize_projections()
                app.init_show_ranges()
                
                
                app.show_imgs()
                
                if ~isfile(replace(app.files{app.filenumEditField.Value},'cell','result'))
                    app.get_segmentations()
                    app.init_thresholding()
                    app.lines_r = {};
                    app.lines_g = {};
                    app.loaded_or_initLabel.Text = 'init';
                else
                    app.load_results()
                    app.loaded_or_initLabel.Text = 'loaded';
                end
                app.points_R_loaded = app.points_R;
                app.points_G_loaded = app.points_G;
                app.points_values_R_loaded = app.points_values_R;
                app.points_values_G_loaded = app.points_values_G;
                app.binaryResuslts_R_loaded = app.binaryResuslts_R;
                app.binaryResuslts_G_loaded = app.binaryResuslts_G;
                app.TredsegSpinner_loaded = app.TredsegSpinner.Value;
                app.TgreensegSpinner_loaded = app.TgreensegSpinner.Value;
                app.lines_r_loaded = app.lines_r;
                app.lines_g_loaded = app.lines_g;

                
                app.get_segmentation_T_R()
                app.get_segmentation_T_G()

                app.redraw_segmentation_R()
                app.redraw_segmentation_G()

                app.show_points()
                app.show_lines_g()
                app.show_lines_r()
                
                
                app.UIFigure.Pointer = 'arrow';
            catch EM
                uialert(app.UIFigure,EM.message,'Load error')
            end
            
        end
        
        function save_results(app)
            
            try 
            
                points_R = app.points_R ;
                points_G = app.points_G;
                points_values_R = app.points_values_R ;
                points_values_G = app.points_values_G;
                binaryResuslts_R = app.binaryResuslts_R;
                binaryResuslts_G = app.binaryResuslts_G;

                TredsegSpinner = app.TredsegSpinner.Value;
                TgreensegSpinner = app.TgreensegSpinner.Value;

                lines_r = app.lines_r;
                lines_g = app.lines_g;
                
                name = replace(app.files{app.filenumEditField.Value},'cell','result');
                save(name,'points_R','points_G','points_values_R','points_values_G','binaryResuslts_R','binaryResuslts_G','TredsegSpinner','TgreensegSpinner','lines_r','lines_g')
                

                app.TredsegSpinner_loaded = app.TredsegSpinner.Value;
                app.TgreensegSpinner_loaded = app.TgreensegSpinner.Value;
                app.lines_r_loaded = app.lines_r;
                app.lines_g_loaded = app.lines_g;
                
                changed = isequal(app.points_R_loaded,app.points_R)&&...
                    isequal(app.points_G_loaded,app.points_G)&&...
                    isequal(app.points_values_R_loaded,app.points_values_R)&&...
                    isequal(app.points_values_G_loaded,app.points_values_G)&&...
                    isequal(app.binaryResuslts_R_loaded,app.binaryResuslts_R)&&...
                    isequal(app.binaryResuslts_G_loaded,app.binaryResuslts_G)&&...
                    isequal(app.lines_r_loaded,app.lines_r)&&...
                    isequal(app.lines_g_loaded,app.lines_g)&&...
                    isequal(app.TredsegSpinner_loaded,app.TredsegSpinner.Value)&&...
                    isequal(app.TgreensegSpinner_loaded,app.TgreensegSpinner.Value);
                changed = changed==0;
                
                if strcmp(app.loaded_or_initLabel.Text,'init') || changed
                
                    strtime_tmp = replace(replace(datestr(now),' ','_'),':','_');
                    name = replace(replace(app.files{app.filenumEditField.Value},'cell','result'),'.mat',['_' strtime_tmp '.mat']);
                    save(name,'points_R','points_G','points_values_R','points_values_G','binaryResuslts_R','binaryResuslts_G')
                    
                    name = replace(replace(app.files{app.filenumEditField.Value},'cell','printscreen'),'.mat',['_' strtime_tmp '.png']);
                    exportapp(app.UIFigure,name)
                
                end

                app.savedLabel.Text = [num2str(app.filenumEditField.Value)  ' saved'];
            catch EM
                uialert(app.UIFigure,EM.message,'Save error')
            
            end
            
        end
        function load_results(app)  
            name = replace(app.files{app.filenumEditField.Value},'cell','result');
            res = load(name);
            
            app.points_R = res.points_R ;
            app.points_G = res.points_G;
            app.points_values_R = res.points_values_R ;
            app.points_values_G = res.points_values_G;
            app.binaryResuslts_R = res.binaryResuslts_R;
            app.binaryResuslts_G = res.binaryResuslts_G;

            app.TredsegSpinner.Value = res.TredsegSpinner;
            app.TgreensegSpinner.Value = res.TgreensegSpinner;

            app.lines_r = res.lines_r;
            app.lines_g = res.lines_g;

        end
        
        function init_params(app)
            app.RdSpinner.Value = app.dSpinner_init.Value;
            tmp = app.data.img_crop(:,:,:,1);
            app.RhSpinner.Value = double(app.data.p95_R-prctile(tmp(app.data.mask_crop),app.hSpinner_init.Value));
            app.RtSpinner.Value = double(prctile(tmp(app.data.mask_crop),app.tSpinner_init.Value));
            app.TredSpinner.Value = double(prctile(tmp(app.data.mask_crop),app.TSpinner_init.Value));
            
            app.GdSpinner.Value = app.dSpinner_init.Value;
            tmp = app.data.img_crop(:,:,:,2);
            app.GhSpinner.Value = double(app.data.p95_G-prctile(tmp(app.data.mask_crop),app.hSpinner_init.Value));
            app.GtSpinner.Value = double(prctile(tmp(app.data.mask_crop),app.tSpinner_init.Value));
            app.TgreenSpinner.Value = double(prctile(tmp(app.data.mask_crop),app.TSpinner_init.Value));
            
        end
        function init_T_params(app)
            tmp = app.data.img_crop(:,:,:,1);
            app.TredSpinner.Value = double(prctile(tmp(app.data.mask_crop),app.TSpinner_init.Value));
            tmp = app.data.img_crop(:,:,:,2);
            app.TgreenSpinner.Value = double(prctile(tmp(app.data.mask_crop),app.TSpinner_init.Value));
        end


        function new_point_R(app)
            app.click_on=0;
            h = drawpoint(app.UIAxes_Rmain);
            app.click_on=1;
            pos=round(h.Position);
            delete(h)
            
            pos(pos<1)=1;
            if pos(1)>size(app.data.img_crop,2)
                return
            end
            if pos(2)>size(app.data.img_crop,1)
                return
            end
            
            [maxv,maxp]= max(app.data.img_crop(pos(2),pos(1),:,1));
            
            app.points_R = [app.points_R;[pos(1),pos(2),round(maxp)]];
            app.points_values_R = [app.points_values_R;maxv];
            
            app.binaryResuslts_R = [app.binaryResuslts_R;true];
            
            app.points_R_all = [app.points_R_all;[pos(1),pos(2),round(maxp)]];
            app.points_values_R_all = [app.points_values_R_all;maxv];
            
            app.show_points_R()
        end
        
        function new_point_G(app)
            app.click_on=0;
            h = drawpoint(app.UIAxes_Gmain);
            app.click_on=1;
            pos=round(h.Position);
            delete(h)
           
            pos(pos<1)=1;
            if pos(1)>size(app.data.img_crop,2)
                return
            end
            if pos(2)>size(app.data.img_crop,1)
                return
            end
                
            [maxv,maxp]= max(app.data.img_crop(pos(2),pos(1),:,2));
            
            app.points_G = [app.points_G;[pos(1),pos(2),round(maxp)]];
            app.points_values_G = [app.points_values_G;maxv];
            
            app.points_G_all = [app.points_G_all;[pos(1),pos(2),round(maxp)]];
            app.points_values_G_all = [app.points_values_G_all;maxv];
            
            app.binaryResuslts_G = [app.binaryResuslts_G;true];
            app.show_points_G()
        end
        
        function newpointcont(app,event)
            if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Rmain)
                 position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                 pos=round([position(1,1),position(1,2)]);
                 
                 pos(pos<1)=1;
                if pos(1)>size(app.data.img_crop,2)
                    return
                end
                if pos(2)>size(app.data.img_crop,1)
                    return
                end
                
                [maxv,maxp]= max(app.data.img_crop(pos(2),pos(1),:,1));
                
                app.points_R = [app.points_R;[pos(1),pos(2),round(maxp)]];
                app.points_values_R = [app.points_values_R;maxv];
                
                app.binaryResuslts_R = [app.binaryResuslts_R;true];
                
                app.points_R_all = [app.points_R_all;[pos(1),pos(2),round(maxp)]];
                app.points_values_R_all = [app.points_values_R_all;maxv];
                
                app.show_points_R()
                 
            end
            
            if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Gmain)
                position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                pos=round([position(1,1),position(1,2)]);
               
                pos(pos<1)=1;
                if pos(1)>size(app.data.img_crop,2)
                    return
                end
                if pos(2)>size(app.data.img_crop,1)
                    return
                end
                    
                [maxv,maxp]= max(app.data.img_crop(pos(2),pos(1),:,2));
                
                app.points_G = [app.points_G;[pos(1),pos(2),round(maxp)]];
                app.points_values_G = [app.points_values_G;maxv];
                
                app.points_G_all = [app.points_G_all;[pos(1),pos(2),round(maxp)]];
                app.points_values_G_all = [app.points_values_G_all;maxv];
                
                app.binaryResuslts_G = [app.binaryResuslts_G;true];
                app.show_points_G()
                 
                 
            end
        end
        
        function shift_point(app,event)
             if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Rmain)
                position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                position=[position(1,1),position(1,2)];
                
                [min_dist,closest]=min((app.points_R(:,1)-position(1)).^2+(app.points_R(:,2)-position(2)).^2);
                if min_dist<30^2
                    app.points_R(closest,1) = position(1);
                    app.points_R(closest,2) = position(2);
                end
                app.show_points_R() 
             end
             
             
             if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Rdown)
                position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                position=[position(1,1),position(1,2)/app.resize_factor];
                
                [min_dist,closest]=min((app.points_R(:,1)-position(1)).^2+(app.points_R(:,3)-position(2)).^2);
                if min_dist<30^2
                    app.points_R(closest,1) = position(1);
                    app.points_R(closest,3) = position(2);
                end
                app.show_points_R() 
                   
             end
             
             
             if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Rleft)
                position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                position=[position(1,1)/app.resize_factor,position(1,2)];
                
                [min_dist,closest]=min((app.points_R(:,3)-position(1)).^2+(app.points_R(:,2)-position(2)).^2);
                if min_dist<30^2
                    app.points_R(closest,3) = position(1);
                    app.points_R(closest,2) = position(2);
                end
                app.show_points_R() 
                   
             end
             
             
             if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Gmain)
                 position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                position=[position(1,1),position(1,2)];
                
                [min_dist,closest]=min((app.points_G(:,1)-position(1)).^2+(app.points_G(:,2)-position(2)).^2);
                if min_dist<30^2
                    app.points_G(closest,1) = position(1);
                    app.points_G(closest,2) = position(2);
                end
                app.show_points_G() 
                   
             end
             
             
             if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Gdown)
                position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                position=[position(1,1),position(1,2)/app.resize_factor];
                
                [min_dist,closest]=min((app.points_G(:,1)-position(1)).^2+(app.points_G(:,3)-position(2)).^2);
                if min_dist<30^2
                    app.points_G(closest,1) = position(1);
                    app.points_G(closest,3) = position(2);
                end
                app.show_points_G() 
                   
             end


             if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Gright)
                 position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                position=[position(1,1)/app.resize_factor,position(1,2)];
                tmp2 = size(app.data.img_crop,3);
                [min_dist,closest]=min((tmp2-app.points_G(:,3)-position(1)).^2+(app.points_G(:,2)-position(2)).^2);
                if min_dist<30^2
                    app.points_G(closest,3) = tmp2-position(1);
                    app.points_G(closest,2) = position(2);
                end
                app.show_points_G() 
             end
        end
        
        function init_thresholding(app)

            p = app.pTRSpinner.Value / 100;
            app.TredsegSpinner.Value = round((1-p) * mean(app.data.img_crop(:,:,:,1),'all') + p * mean(app.points_values_R));

            p = app.pTGSpinner.Value / 100;
            app.TgreensegSpinner.Value = round((1-p) * mean(app.data.img_crop(:,:,:,2),'all') + p * mean(app.points_values_G));
        end
        
        
        function get_segmentation_T_R(app)


            segm_R = app.data.img_crop(:,:,:,1) > app.TredsegSpinner.Value;

            app.segm_R_main=max(segm_R,[],3);

            app.segm_R_left=squeeze(max(segm_R,[],2));

            app.segm_R_down=squeeze(max(segm_R,[],1))';
            

            app.segm_R_left=imresize(app.segm_R_left,[size(app.segm_R_left,1),round(size(app.segm_R_left,2)*app.resize_factor)]);

            app.segm_R_down=imresize(app.segm_R_down,[round(size(app.segm_R_down,1)*app.resize_factor),size(app.segm_R_down,2)]);
            
        end


        function get_segmentation_T_G(app)

            segm_G = app.data.img_crop(:,:,:,2) > app.TgreensegSpinner.Value;

            app.segm_G_main=max(segm_G,[],3);

            app.segm_G_right=squeeze(max(segm_G,[],2));
            app.segm_G_right=app.segm_G_right(:,end:-1:1);

            app.segm_G_down=squeeze(max(segm_G,[],1))';

            app.segm_G_right=imresize(app.segm_G_right,[size(app.segm_G_right,1),round(size(app.segm_G_right,2)*app.resize_factor)]);
            app.segm_G_down=imresize(app.segm_G_down,[round(size(app.segm_G_down,1)*app.resize_factor),size(app.segm_G_down,2)]);
            
        end
        
        function redraw_segmentation_R(app)
            color = 'g';
            lw = 0.2;

            hold(app.UIAxes_Rmain, 'on')
            if ~isempty(app.contours_main_r)
                delete(app.contours_main_r);
            end
            if app.showmasksyCheckBox.Value
                app.contours_main_r = visboundaries(app.UIAxes_Rmain,app.segm_R_main>0,"Color",color,'LineWidth',lw,'EnhanceVisibility',0);
            end


            hold(app.UIAxes_Rleft, 'on')
            if ~isempty(app.contours_left_r)
                delete(app.contours_left_r);
            end
            if app.showmasksyCheckBox.Value
                app.contours_left_r = visboundaries(app.UIAxes_Rleft,app.segm_R_left>0,"Color",color,'LineWidth',lw,'EnhanceVisibility',0);
            end


            hold(app.UIAxes_Rdown, 'on')
            if ~isempty(app.contours_down_r)
                delete(app.contours_down_r);
            end
            if app.showmasksyCheckBox.Value
                app.contours_down_r = visboundaries(app.UIAxes_Rdown,app.segm_R_down>0,"Color",color,'LineWidth',lw,'EnhanceVisibility',0);
            end


        end

        function redraw_segmentation_G(app)
            color = 'r';
            lw = 0.2;

            hold(app.UIAxes_Gmain, 'on')
            if ~isempty(app.contours_main_g)
                delete(app.contours_main_g);
            end
            if app.showmasksyCheckBox.Value
                app.contours_main_g = visboundaries(app.UIAxes_Gmain,app.segm_G_main>0,"Color",color,'LineWidth',lw,'EnhanceVisibility',0);
            end


            hold(app.UIAxes_Gright, 'on')
            if ~isempty(app.contours_right_g)
                delete(app.contours_right_g);
            end
            if app.showmasksyCheckBox.Value
                app.contours_right_g = visboundaries(app.UIAxes_Gright,app.segm_G_right>0,"Color",color,'LineWidth',lw,'EnhanceVisibility',0);
            end


            hold(app.UIAxes_Gdown, 'on')
            if ~isempty(app.contours_down_g)
                delete(app.contours_down_g);
            end
            if app.showmasksyCheckBox.Value
                app.contours_down_g = visboundaries(app.UIAxes_Gdown,app.segm_G_down>0,"Color",color,'LineWidth',lw,'EnhanceVisibility',0);
            end

        end

        function line = draw_line(app, UIAxes_)
            app.click_on=0;
            h=drawfreehand(UIAxes_,'Closed',0);
            app.click_on=1;
            draw_image=h.createMask(app.data.rImg_main);

            P0 = h.Position;

            delete(h);
            
%             [x,y]=find(draw_image);
%             P00=[y,x];
%             
%             F = false(size(draw_image));
% 
%             % P0 = unique(round(P00),'rows');
%             P00=P00+(rand(size(P00))-0.6);
%             [~,ia1,~]=unique(round(P00(:,1)*10)/10,'rows');
%             [~,ia2,~]=unique(round(P00(:,2)*10)/10,'rows');
%             vektor=zeros(1,size(P00,1));
%             vektor(ia1)=vektor(ia1)+1;
%             vektor(ia2)=vektor(ia2)+2;
%             P0=P00(find(vektor>0),:);
            
            D = round([0; cumsum(sqrt(sum((diff(P0)).^2,2)))]);
            [D,ia1,~]=unique(D,'rows');
            P0=P0(ia1,:);
            P = interp1(D,P0,D(1):.3:D(end)); % ...to close the gaps
            
%             P = unique(round(P),'rows');
%             P = round(P);
            
            P(P<1)=1;
            P1=P(:,1);
            P2=P(:,2);
            P1(P1>size(draw_image,2))=size(draw_image,2);
            P2(P2>size(draw_image,1))=size(draw_image,1);
            line = [P1,P2];
        end
        
        function draw_G_line(app)
            line = app.draw_line(app.UIAxes_Gmain);
            app.lines_g = [app.lines_g,line];
            drawnow;
            app.show_lines_g()
        end
        function draw_R_line(app)
            line = app.draw_line(app.UIAxes_Rmain);
            app.lines_r = [app.lines_r,line];
            app.show_lines_r()

        end
        function show_lines_r(app)
            color = 'y';
            lw = 2;

            for k = 1:length(app.lines_r_plots)
                delete(app.lines_r_plots(k));
            end

            app.lines_r_plots = {};
            if app.showlinesyCheckBox.Value
                for k = 1:length(app.lines_r)
                    line = app.lines_r{k};
                    p = plot(app.UIAxes_Rmain, line(:,1),line(:,2),"LineWidth",lw, "Color",color);
                    app.lines_r_plots = [app.lines_r_plots,p];
                end
            end
        end

        function show_lines_g(app)
            color = 'y';
            lw = 2;

            for k = 1:length(app.lines_g_plots)
                delete(app.lines_g_plots(k));
            end
            
            app.lines_g_plots = {};
            if app.showlinesyCheckBox.Value
                for k = 1:length(app.lines_g)
                    line = app.lines_g{k};
                    p = plot(app.UIAxes_Gmain, line(:,1),line(:,2),"LineWidth",lw, "Color",color);
                    app.lines_g_plots = [app.lines_g_plots,p];
                end
            end
        end
        function delete_line_R(app)
            app.click_on=0;
            h = drawpoint(app.UIAxes_Rmain);
            app.click_on=1;
            pos=round(h.Position);
            delete(h)
           
            pos(pos<1)=1;
            if pos(1)>size(app.data.img_crop,2)
                return
            end
            if pos(2)>size(app.data.img_crop,1)
                return
            end

            nearest_line = 0;
            nearest_distance = 999999;
            for k = 1:length(app.lines_r)
                line = app.lines_r{k};
                mindist = min(sqrt((line(:,1) - pos(1)) .^2 + (line(:,2) - pos(2)) .^2));
                if mindist< nearest_distance
                    nearest_line = k;
                    nearest_distance = mindist;
                end
            end

            if nearest_distance < 30
                app.lines_r(nearest_line) = [];
            end


        end
        function delete_line_G(app)
            app.click_on=0;
            h = drawpoint(app.UIAxes_Gmain);
            app.click_on=1;
            pos=round(h.Position);
            delete(h)
           
            pos(pos<1)=1;
            if pos(1)>size(app.data.img_crop,2)
                return
            end
            if pos(2)>size(app.data.img_crop,1)
                return
            end

            nearest_line = 0;
            nearest_distance = 999999;
            for k = 1:length(app.lines_g)
                line = app.lines_g{k};
                mindist = min(sqrt((line(:,1) - pos(1)) .^2 + (line(:,2) - pos(2)) .^2));
                if mindist< nearest_distance
                    nearest_line = k;
                    nearest_distance = mindist;
                end
            end

            if nearest_distance < 30
                app.lines_g(nearest_line) = [];
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            app.resize_factor = 3;
            app.click_on = 1;
            app.TredSpinner.Step = app.stepRSpinner.Value;
            app.TgreenSpinner.Step = app.stepGSpinner.Value;
            app.large_point_R = [];
            app.large_point_G = [];
            
%             drawnow;
%             path = uigetdir('.','Select preprocessed folder');

            path = '../Prioritně + 15N  ion tracks (originálně z Acquiarium) pro analýzu a nové učení_labeling_new';
            app.files = subdir([path '/cell*.mat']);
            app.files = {app.files(:).name};
            app.files = app.files(cellfun(@(x) ~contains(x,'cell_orig'),app.files));


%             file_names = load('random_filenames.mat');
%             app.files = [file_names.filenames_sets{:}];
            
            app.numfilesLabel.Text =  ['/' num2str(length(app.files))];
            
            
            app.filenumEditField.Value = 1;
            for k = 1:length(app.files)
                if ~isfile(replace(app.files{k},'cell','result'))
                    app.filenumEditField.Value = k-1;
                    break
                end
            end
            if app.filenumEditField.Value==0
                app.filenumEditField.Value=1;
                
            end
            app.filenumEditField.Limits = [1 length(app.files)];
            
            app.init_next()

        end

        % Button pushed function: Button_next
        function Button_nextPushed(app, event)
            app.save_results()
            if (app.filenumEditField.Value+1)<=length(app.files)
                app.filenumEditField.Value = app.filenumEditField.Value+1;
            end
            app.init_next()
                
        end

        % Button pushed function: Button_prev
        function Button_prevPushed(app, event)
            app.save_results()
            if (app.filenumEditField.Value-1)>0
                app.filenumEditField.Value = app.filenumEditField.Value-1;
            end
            app.init_next()
        end

        % Value changed function: filenumEditField
        function filenumEditFieldValueChanged(app, event)
            app.filenumEditField.Value = event.PreviousValue;
            app.save_results()
            app.filenumEditField.Value = event.Value;
            app.init_next()
        end

        % Value changed function: Spinner_Rmin
        function Spinner_RminValueChanged(app, event)
            app.show_img_R()
            app.show_points_R()
            
        end

        % Value changed function: Spinner_Rmax
        function Spinner_RmaxValueChanged(app, event)
            app.show_img_R()
            app.show_points_R()
        end

        % Value changed function: Spinner_Gmin
        function Spinner_GminValueChanged(app, event)
            app.show_img_G()
            app.show_points_G()
        end

        % Value changed function: Spinner_Gmax
        function Spinner_GmaxValueChanged(app, event)
            app.show_img_G()
            app.show_points_G()
        end

        % Value changed function: Spinner_Bmin
        function Spinner_BminValueChanged(app, event)
            app.show_imgs()
            app.show_points()
        end

        % Value changed function: Spinner_Bmax
        function Spinner_BmaxValueChanged(app, event)
            app.show_imgs()
            app.show_points()
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            try 
                if app.click_on
                     if app.newpointcontbCheckBox.Value
                         app.newpointcont(event)
                         return
                     end
                     if strcmp(app.UIFigure.SelectionType,'extend')
                        app.shift_point(event)
                     end
                     if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Rmain)
                         position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                         position=[position(1,1),position(1,2)];
                        
                         if app.editpointsvCheckBox.Value==0
                             [min_dist,closest]=min((app.points_R(:,1)-position(1)).^2+(app.points_R(:,2)-position(2)).^2);
                             if min_dist<30^2
                                 if strcmp(app.UIFigure.SelectionType,'normal')
                                     tmp=1;
                                 elseif strcmp(app.UIFigure.SelectionType,'alt')
                                     tmp=0;                              
                                 end
                             end
                             app.binaryResuslts_R(closest)=tmp;
                             app.large_point_R = closest;
                             app.show_points_R()  
                         else
                             
                             if strcmp(app.UIFigure.SelectionType,'normal')
                                 
                                 
                                 hold(event.Source.CurrentObject.Parent, 'on')
                                 tmp = 0;
                                 if ~isempty(app.selected_point)
                                    if isvalid(app.selected_point)
                                        tmp=1;
                                    end
                                 end
                                 if tmp
                                     delete(app.selected_point)
                                 else
                                     [min_dist,closest]=min((app.points_R(:,1)-position(1)).^2+(app.points_R(:,2)-position(2)).^2);
                                     if min_dist<30^2
                                        app.selected_point=plot(event.Source.CurrentObject.Parent,app.points_R(closest,1), app.points_R(closest,2), 'y*','MarkerSize',6);
                                        app.selected_point_ind = closest;
                                        app.selected_point_axes = event.Source.CurrentObject.Parent;
                                     end
                                 end
                             elseif strcmp(app.UIFigure.SelectionType,'alt')
                                 if ~isempty(app.selected_point)
                                     if isvalid(app.selected_point)
                                         if isequal(event.Source.CurrentObject.Parent,app.selected_point_axes)
                                             delete(app.selected_point)
                                             app.points_R(app.selected_point_ind,1) = position(1);
                                             app.points_R(app.selected_point_ind,2) = position(2);
                                             app.show_points_R()  
                                         end
                                     end
                                 end
    
                             end
                         end
                           
                     end


                     if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Rdown)
                         position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                         position=[position(1,1),position(1,2)/app.resize_factor];
                        
                         if app.editpointsvCheckBox.Value==0
                             [min_dist,closest]=min((app.points_R(:,1)-position(1)).^2+(app.points_R(:,3)-position(2)).^2);
                             if min_dist<30^2
                                 if strcmp(app.UIFigure.SelectionType,'normal')
                                     tmp=1;
                                 elseif strcmp(app.UIFigure.SelectionType,'alt')
                                     tmp=0;                              
                                 end
                             end
                             app.binaryResuslts_R(closest)=tmp;
                             app.large_point_R = closest;
                             app.show_points_R()  
                         else
                             
                             if strcmp(app.UIFigure.SelectionType,'normal')
                                 
                                 
                                 hold(event.Source.CurrentObject.Parent, 'on')
                                 tmp = 0;
                                 if ~isempty(app.selected_point)
                                    if isvalid(app.selected_point)
                                        tmp=1;
                                    end
                                 end
                                 if tmp
                                     delete(app.selected_point)
                                 else
                                     [min_dist,closest]=min((app.points_R(:,1)-position(1)).^2+(app.points_R(:,3)-position(2)).^2);
                                     if min_dist<30^2
                                        app.selected_point=plot(event.Source.CurrentObject.Parent,app.points_R(closest,1), app.points_R(closest,3)*app.resize_factor, 'y*','MarkerSize',6);
                                        app.selected_point_ind = closest;
                                        app.selected_point_axes = event.Source.CurrentObject.Parent;
                                     end
                                 end
                             elseif strcmp(app.UIFigure.SelectionType,'alt')
                                 if ~isempty(app.selected_point)
                                     if isvalid(app.selected_point)
                                         if isequal(event.Source.CurrentObject.Parent,app.selected_point_axes)
                                             delete(app.selected_point)
                                             delete(app.selected_point)
                                             app.points_R(app.selected_point_ind,1) = position(1);
                                             app.points_R(app.selected_point_ind,3) = position(2);
                                             app.show_points_R()  
                                         end
                                     end
                                 end
    
                             end
                         end
                           
                     end


                     if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Rleft)
                         position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                         position=[position(1,1)/app.resize_factor,position(1,2)];
                        
                         if app.editpointsvCheckBox.Value==0
                             
                             [min_dist,closest]=min((app.points_R(:,3)-position(1)).^2+(app.points_R(:,2)-position(2)).^2);
                             if min_dist<30^2
                                 if strcmp(app.UIFigure.SelectionType,'normal')
                                     tmp=1;
                                 elseif strcmp(app.UIFigure.SelectionType,'alt')
                                     tmp=0;                              
                                 end
                             end
                             app.binaryResuslts_R(closest)=tmp;
                             app.large_point_R = closest;
                             app.show_points_R()  
                         else
    
                             if strcmp(app.UIFigure.SelectionType,'normal')
                                 
                                 hold(event.Source.CurrentObject.Parent, 'on')
                                 tmp = 0;
                                 if ~isempty(app.selected_point)
                                    if isvalid(app.selected_point)
                                        tmp=1;
                                    end
                                 end
                                 if tmp
                                     delete(app.selected_point)
                                 else
                                     
                                     [min_dist,closest]=min((app.points_R(:,3)-position(1)).^2+(app.points_R(:,2)-position(2)).^2);
                                     if min_dist<30^2
                                        app.selected_point=plot(event.Source.CurrentObject.Parent,app.points_R(closest,3)*app.resize_factor, app.points_R(closest,2), 'y*','MarkerSize',6);
                                        app.selected_point_ind = closest;
                                        app.selected_point_axes = event.Source.CurrentObject.Parent;
                                     end
                                 end
                             elseif strcmp(app.UIFigure.SelectionType,'alt')
                                 if ~isempty(app.selected_point)
                                     if isvalid(app.selected_point)
                                         if isequal(event.Source.CurrentObject.Parent,app.selected_point_axes)
                                             delete(app.selected_point)
                                             delete(app.selected_point)
                                             app.points_R(app.selected_point_ind,3) = position(1);
                                             app.points_R(app.selected_point_ind,2) = position(2);
                                             app.show_points_R()  
                                         end
                                     end
                                 end
    
                             end
                         end
                           
                     end


                     if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Gmain)
                         position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                         position=[position(1,1),position(1,2)];
                        
                         if app.editpointsvCheckBox.Value==0
                             [min_dist,closest]=min((app.points_G(:,1)-position(1)).^2+(app.points_G(:,2)-position(2)).^2);
                             if min_dist<30^2
                                 if strcmp(app.UIFigure.SelectionType,'normal')
                                     tmp=1;
                                 elseif strcmp(app.UIFigure.SelectionType,'alt')
                                     tmp=0;                              
                                 end
                             end
                             app.binaryResuslts_G(closest)=tmp;
                             app.large_point_G = closest;
                             app.show_points_G()  
                         else
                             
                             if strcmp(app.UIFigure.SelectionType,'normal')
                                 
                                 
                                 hold(event.Source.CurrentObject.Parent, 'on')
                                 tmp = 0;
                                 if ~isempty(app.selected_point)
                                    if isvalid(app.selected_point)
                                        tmp=1;
                                    end
                                 end
                                 if tmp
                                     delete(app.selected_point)
                                 else
                                     [min_dist,closest]=min((app.points_G(:,1)-position(1)).^2+(app.points_G(:,2)-position(2)).^2);
                                     if min_dist<30^2
                                        app.selected_point=plot(event.Source.CurrentObject.Parent,app.points_G(closest,1), app.points_G(closest,2), 'y*','MarkerSize',6);
                                        app.selected_point_ind = closest;
                                        app.selected_point_axes = event.Source.CurrentObject.Parent;
                                     end
                                 end
                             elseif strcmp(app.UIFigure.SelectionType,'alt')
                                 if ~isempty(app.selected_point)
                                     if isvalid(app.selected_point)
                                         if isequal(event.Source.CurrentObject.Parent,app.selected_point_axes)
                                             delete(app.selected_point)
                                             app.points_G(app.selected_point_ind,1) = position(1);
                                             app.points_G(app.selected_point_ind,2) = position(2);
                                             app.show_points_G()  
                                         end
                                     end
                                 end
    
                             end
                         end
                           
                     end


                     if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Gdown)
                         position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                         position=[position(1,1),position(1,2)/app.resize_factor];
                        
                         if app.editpointsvCheckBox.Value==0
                             [min_dist,closest]=min((app.points_G(:,1)-position(1)).^2+(app.points_G(:,3)-position(2)).^2);
                             if min_dist<30^2
                                 if strcmp(app.UIFigure.SelectionType,'normal')
                                     tmp=1;
                                 elseif strcmp(app.UIFigure.SelectionType,'alt')
                                     tmp=0;                              
                                 end
                             end
                             app.binaryResuslts_G(closest)=tmp;
                             app.large_point_G = closest;
                             app.show_points_G()  
                         else
                             
                             if strcmp(app.UIFigure.SelectionType,'normal')
                                 
                                 
                                 hold(event.Source.CurrentObject.Parent, 'on')
                                 tmp = 0;
                                 if ~isempty(app.selected_point)
                                    if isvalid(app.selected_point)
                                        tmp=1;
                                    end
                                 end
                                 if tmp
                                     delete(app.selected_point)
                                 else
                                     [min_dist,closest]=min((app.points_G(:,1)-position(1)).^2+(app.points_G(:,3)-position(2)).^2);
                                     if min_dist<30^2
                                        app.selected_point=plot(event.Source.CurrentObject.Parent,app.points_G(closest,1), app.points_G(closest,3)*app.resize_factor, 'y*','MarkerSize',6);
                                        app.selected_point_ind = closest;
                                        app.selected_point_axes = event.Source.CurrentObject.Parent;
                                     end
                                 end
                             elseif strcmp(app.UIFigure.SelectionType,'alt')
                                 if ~isempty(app.selected_point)
                                     if isvalid(app.selected_point)
                                         if isequal(event.Source.CurrentObject.Parent,app.selected_point_axes)
                                             delete(app.selected_point)
                                             delete(app.selected_point)
                                             app.points_G(app.selected_point_ind,1) = position(1);
                                             app.points_G(app.selected_point_ind,3) = position(2);
                                             app.show_points_G()  
                                         end
                                     end
                                 end
    
                             end
                         end
                           
                     end


                     if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Gright)
                         position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                         position=[position(1,1)/app.resize_factor,position(1,2)];
                        
                         if app.editpointsvCheckBox.Value==0
                             tmp2 = size(app.data.img_crop,3);
                             [min_dist,closest]=min((tmp2-app.points_G(:,3)-position(1)).^2+(app.points_G(:,2)-position(2)).^2);
                             if min_dist<30^2
                                 if strcmp(app.UIFigure.SelectionType,'normal')
                                     tmp=1;
                                 elseif strcmp(app.UIFigure.SelectionType,'alt')
                                     tmp=0;                              
                                 end
                             end
                             app.binaryResuslts_G(closest)=tmp;
                             app.large_point_G = closest;
                             app.show_points_G()  
                         else
                             tmp2 = size(app.data.img_crop,3);
                             if strcmp(app.UIFigure.SelectionType,'normal')
                                 
                                 hold(event.Source.CurrentObject.Parent, 'on')
                                 tmp = 0;
                                 if ~isempty(app.selected_point)
                                    if isvalid(app.selected_point)
                                        tmp=1;
                                    end
                                 end
                                 if tmp
                                     delete(app.selected_point)
                                 else
                                     
                                     [min_dist,closest]=min((tmp2-app.points_G(:,3)-position(1)).^2+(app.points_G(:,2)-position(2)).^2);
                                     if min_dist<30^2
                                        app.selected_point=plot(event.Source.CurrentObject.Parent,(tmp2-app.points_G(closest,3))*app.resize_factor, app.points_G(closest,2), 'y*','MarkerSize',6);
                                        app.selected_point_ind = closest;
                                        app.selected_point_axes = event.Source.CurrentObject.Parent;
                                     end
                                 end
                             elseif strcmp(app.UIFigure.SelectionType,'alt')
                                 if ~isempty(app.selected_point)
                                     if isvalid(app.selected_point)
                                         if isequal(event.Source.CurrentObject.Parent,app.selected_point_axes)
                                             delete(app.selected_point)
                                             delete(app.selected_point)
                                             app.points_G(app.selected_point_ind,3) = tmp2-position(1);
                                             app.points_G(app.selected_point_ind,2) = position(2);
                                             app.show_points_G()  
                                         end
                                     end
                                 end
    
                             end
                         end
                           
                     end
                end


            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.save_results()
            app.delete
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,'RESET?','Confirm Close',...
                        'Icon','warning');
            if strcmp(selection,'OK')
                name = replace(app.files{app.filenumEditField.Value},'cell','result');
                if isfile(name)
                    delete(name)
                end
                
                app.init_next()
            end
        end

        % Button pushed function: savesettingsButton
        function savesettingsButtonPushed(app, event)
            [file,path] = uiputfile('.mat','Save settings','settings.mat');
            
            initpercSpinner_minR = app.initpercSpinner_minR.Value;
            initpercSpinner_maxR = app.initpercSpinner_maxR.Value;
            
            initpercSpinner_minG = app.initpercSpinner_minG.Value;
            initpercSpinner_maxG = app.initpercSpinner_maxG.Value;
            
            initpercSpinner_minB = app.initpercSpinner_minB.Value;
            initpercSpinner_maxB = app.initpercSpinner_maxB.Value;
            
            pTRSpinner = app.pTRSpinner.Value;
            pTGSpinner = app.pTGSpinner.Value;

            
            dSpinner_init = app.dSpinner_init.Value;
            hSpinner_init = app.hSpinner_init.Value;
            tSpinner_init = app.tSpinner_init.Value;
            TSpinner_init = app.TSpinner_init.Value;
            stepRSpinner = app.stepRSpinner.Value;
            stepGSpinner = app.stepGSpinner.Value; 

            save([path filesep file],'dSpinner_init','hSpinner_init','tSpinner_init','TSpinner_init',...
                'stepRSpinner','stepGSpinner','initpercSpinner_minR','initpercSpinner_maxR',...
                'initpercSpinner_minG','initpercSpinner_maxG','initpercSpinner_minB','initpercSpinner_maxB','pTRSpinner','pTGSpinner')
        end

        % Button pushed function: loadsettingsButton
        function loadsettingsButtonPushed(app, event)
            [file,path] = uigetfile('.mat','Load settings','settings.mat');
            
            tmp = load([path filesep file]);
            
            
            app.initpercSpinner_minR.Value = tmp.initpercSpinner_minR;
            app.initpercSpinner_maxR.Value = tmp.initpercSpinner_maxR;
            app.initpercSpinner_minG.Value = tmp.initpercSpinner_minG;
            app.initpercSpinner_maxG.Value = tmp.initpercSpinner_maxG;
            app.initpercSpinner_minB.Value = tmp.initpercSpinner_minB;
            app.initpercSpinner_maxB.Value = tmp.initpercSpinner_maxB;
            app.dSpinner_init.Value = tmp.dSpinner_init;
            app.hSpinner_init.Value = tmp.hSpinner_init;
            app.tSpinner_init.Value = tmp.tSpinner_init;
            app.TSpinner_init.Value = tmp.TSpinner_init;
            app.stepRSpinner.Value = tmp.stepRSpinner;
            app.stepGSpinner.Value = tmp.stepGSpinner; 

            app.pTRSpinner.Value = tmp.pTRSpinner;
            app.pTGSpinner.Value = tmp.pTGSpinner; 

        end

        % Button pushed function: clearfolderButton
        function clearfolderButtonPushed(app, event)
            
            selection = uiconfirm(app.UIFigure,'This will delete existing results in selected folder!','Confirm Close','Icon','warning');
            if strcmp(selection,'OK')
                path = [];
                path = uigetdir('.','Select folder to delete results.');
                if path
                    selection = uiconfirm(app.UIFigure,['Delete selected folder?' '(' path ')'],'Confirm Close','Icon','warning');
                    if strcmp(selection,'OK')
                    
                        to_delete = subdir([path '/result*.mat']);
                        for k=1:length(to_delete)
                            delete(to_delete(k).name)
                        end
                        to_delete = subdir([path '/example*.png']);
                        for k=1:length(to_delete)
                            delete(to_delete(k).name)
                        end
                    end
                end
            end
        end

        % Value changed function: showpointscCheckBox
        function showpointscCheckBoxValueChanged(app, event)
            app.show_points()
        end

        % Button pushed function: newpointButton_R
        function newpointButton_RPushed(app, event)
            app.new_point_R()
        end

        % Button pushed function: newpointButton_G
        function newpointButton_GPushed(app, event)
            app.new_point_G()
        end

        % Value changed function: TredSpinner
        function TredSpinnerValueChanged(app, event)
            
            tmp1 = round(app.points_R(:,2));
            tmp2 = round(app.points_R(:,1));
            tmp3 = round(app.points_R(:,3));
            
            app.points_values_R = app.data.img_crop(sub2ind(size(app.data.img_crop),tmp1,tmp2,tmp3,ones(size(app.points_R,1),1)));
            app.binaryResuslts_R = app.points_values_R>app.TredSpinner.Value;
            app.show_points_R()
            
        end

        % Value changed function: TgreenSpinner
        function TgreenSpinnerValueChanged(app, event)
            
            tmp1 = round(app.points_G(:,2));
            tmp2 = round(app.points_G(:,1));
            tmp3 = round(app.points_G(:,3));
            
            app.points_values_G = app.data.img_crop(sub2ind(size(app.data.img_crop),tmp1,tmp2,tmp3,2*ones(size(app.points_G,1),1)));
            app.binaryResuslts_G = app.points_values_G>app.TgreenSpinner.Value;
            app.show_points_G()
            
        end

        % Value changed function: initpercSpinner_minB
        function initpercSpinner_minBValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.init_show_ranges()
            app.show_imgs()
            app.show_points()
            app.UIFigure.Pointer = 'arrow';

        end

        % Value changed function: dSpinner_init
        function dSpinner_initValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.init_params()
            app.get_segmentations()
            app.show_points()
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: hSpinner_init
        function hSpinner_initValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.init_params()
            app.get_segmentations()
            app.show_points()
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: tSpinner_init
        function tSpinner_initValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.init_params()
            use = app.points_values_R_all>app.RtSpinner.Value;
            app.points_values_R = app.points_values_R_all(use);
            app.points_R = app.points_R_all(use,:);
            
            app.binaryResuslts_R = app.points_values_R>app.TredSpinner.Value;
            
            use = app.points_values_G_all>app.GtSpinner.Value;
            app.points_values_G = app.points_values_G_all(use);
            app.points_G = app.points_G_all(use,:);
            
            app.binaryResuslts_G = app.points_values_G>app.TgreenSpinner.Value; 
            
            app.show_points()
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: TSpinner_init
        function TSpinner_initValueChanged(app, event)
            
            app.init_T_params()
            
            tmp1 = round(app.points_R(:,2));
            tmp2 = round(app.points_R(:,1));
            tmp3 = round(app.points_R(:,3));
            
            app.points_values_R = app.data.img_crop(sub2ind(size(app.data.img_crop),tmp1,tmp2,tmp3,ones(size(app.points_R,1),1)));
            app.binaryResuslts_R = app.points_values_R>app.TredSpinner.Value;
            app.show_points_R()
            
            
            tmp1 = round(app.points_G(:,2));
            tmp2 = round(app.points_G(:,1));
            tmp3 = round(app.points_G(:,3));
            
            app.points_values_G = app.data.img_crop(sub2ind(size(app.data.img_crop),tmp1,tmp2,tmp3,2*ones(size(app.points_G,1),1)));
            app.binaryResuslts_G = app.points_values_G>app.TgreenSpinner.Value;
            app.show_points_G()
            
            
        end

        % Value changed function: stepGSpinner
        function stepGSpinnerValueChanged(app, event)
            app.TgreenSpinner.Step = app.stepGSpinner.Value;
        end

        % Value changed function: stepRSpinner
        function stepRSpinnerValueChanged(app, event)
            app.TredSpinner.Step = app.stepRSpinner.Value;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)

        end

        % Value changed function: RdSpinner
        function RdSpinnerValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.get_segmentations_R()
            app.show_points_R()
            app.UIFigure.Pointer = 'arrow';
            
        end

        % Value changed function: RhSpinner
        function RhSpinnerValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.get_segmentations_R()
            app.show_points_R()
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: RtSpinner
        function RtSpinnerValueChanged(app, event)
           app.UIFigure.Pointer = 'watch';
            drawnow;
            use = app.points_values_R_all>app.RtSpinner.Value;
            app.points_values_R = app.points_values_R_all(use);
            app.points_R = app.points_R_all(use,:);
            
            app.binaryResuslts_R = app.points_values_R>app.TredSpinner.Value;
            
            app.show_points_R()
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: GdSpinner
        function GdSpinnerValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.get_segmentations_G()
            app.show_points_G()
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: GhSpinner
        function GhSpinnerValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.get_segmentations_G()
            app.show_points_G()
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: GtSpinner
        function GtSpinnerValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            use = app.points_values_G_all>app.GtSpinner.Value;
            app.points_values_G = app.points_values_G_all(use);
            app.points_G = app.points_G_all(use,:);
            
            app.binaryResuslts_G = app.points_values_G>app.TgreenSpinner.Value; 
            app.show_points_G()
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: initpercSpinner_maxB
        function initpercSpinner_maxBValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.init_show_ranges()
            app.show_imgs()
            app.show_points()
            app.UIFigure.Pointer = 'arrow';
            
        end

        % Value changed function: editpointsvCheckBox
        function editpointsvCheckBoxValueChanged(app, event)
            value = app.editpointsvCheckBox.Value;
            if value
                app.newpointcontbCheckBox.Value = 0;
            end
        end

        % Value changed function: newpointcontbCheckBox
        function newpointcontbCheckBoxValueChanged(app, event)
            value = app.newpointcontbCheckBox.Value;
            if value
                app.editpointsvCheckBox.Value = 0;
            end
        end

        % Value changed function: showoriginalleftaltCheckBox
        function showoriginalleftaltCheckBoxValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.show_imgs()
            app.show_points()
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: showRGBxCheckBox
        function showRGBxCheckBoxValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.show_imgs()
            app.show_points()
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: initpercSpinner_minG
        function initpercSpinner_minGValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.init_show_ranges()
            app.show_imgs()
            app.show_points()
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: initpercSpinner_minR
        function initpercSpinner_minRValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.init_show_ranges()
            app.show_imgs()
            app.show_points()
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: initpercSpinner_maxR
        function initpercSpinner_maxRValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.init_show_ranges()
            app.show_imgs()
            app.show_points()
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: initpercSpinner_maxG
        function initpercSpinner_maxGValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            app.init_show_ranges()
            app.show_imgs()
            app.show_points()
            app.UIFigure.Pointer = 'arrow';
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.save_results()
            app.init_next()
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'v')
                app.editpointsvCheckBox.Value = ~app.editpointsvCheckBox.Value;
                value = app.editpointsvCheckBox.Value;
                if value
                    app.newpointcontbCheckBox.Value = 0;
                end
            end
            if strcmp(key,'b')
                app.newpointcontbCheckBox.Value = ~app.newpointcontbCheckBox.Value;
                value = app.newpointcontbCheckBox.Value;
                if value
                    app.editpointsvCheckBox.Value = 0;
                end
            end
            if strcmp(key,'n')
                 if isequal(event.Source.CurrentAxes,app.UIAxes_Rmain)
                    app.new_point_R()
                 end
                 if isequal(event.Source.CurrentAxes,app.UIAxes_Gmain)
                    app.new_point_G()
                 end
            end
            if strcmp(key,'m')
                if isequal(event.Source.CurrentAxes,app.UIAxes_Rmain)
                    app.draw_R_line()
                 end
                 if isequal(event.Source.CurrentAxes,app.UIAxes_Gmain)
                    app.draw_G_line()
                 end
            end
            if strcmp(key,'c')
                app.showpointscCheckBox.Value = ~app.showpointscCheckBox.Value;
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.show_imgs()
                app.show_points()
                app.UIFigure.Pointer = 'arrow';
                
            end
            if strcmp(key,'x')
                app.showRGBxCheckBox.Value = ~app.showRGBxCheckBox.Value;
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.show_imgs()
                app.show_points()
                app.UIFigure.Pointer = 'arrow';
            end
            if strcmp(key,'alt')
                app.showoriginalleftaltCheckBox.Value = ~app.showoriginalleftaltCheckBox.Value;
                app.UIFigure.Pointer = 'watch';
                drawnow;
                app.show_imgs()
                app.show_points()
                app.UIFigure.Pointer = 'arrow';
            end

            if strcmp(key,'y')
                app.showmasksyCheckBox.Value = ~app.showmasksyCheckBox.Value;
                app.showlinesyCheckBox.Value = ~app.showlinesyCheckBox.Value;
                app.UIFigure.Pointer = 'watch';
                app.show_lines_r()
                app.show_lines_g()
                app.redraw_segmentation_R()
                app.redraw_segmentation_G()
                app.UIFigure.Pointer = 'arrow';
            end
            if strcmp(key,'comma')
                 if isequal(event.Source.CurrentAxes,app.UIAxes_Rmain)
                    app.delete_line_R();
                    app.show_lines_r()
                 end
                 if isequal(event.Source.CurrentAxes,app.UIAxes_Gmain)
                    app.delete_line_G();
                    app.show_lines_g()
                 end
            end


        end

        % Value changed function: TredsegSpinner
        function TredsegSpinnerValueChanged(app, event)
            value = app.TredsegSpinner.Value;
            app.get_segmentation_T_R()
            app.redraw_segmentation_R()
        end

        % Value changed function: TgreensegSpinner
        function TgreensegSpinnerValueChanged(app, event)
            value = app.TgreensegSpinner.Value;
            app.get_segmentation_T_G()
            app.redraw_segmentation_G()
        end

        % Value changed function: showmasksyCheckBox
        function showmasksyCheckBoxValueChanged(app, event)
            value = app.showmasksyCheckBox.Value;
            app.redraw_segmentation_R()
            app.redraw_segmentation_G()
        end

        % Button pushed function: drawRButton
        function drawRButtonPushed(app, event)
            app.draw_R_line()
        end

        % Button pushed function: drawGButton
        function drawGButtonPushed(app, event)
            app.draw_G_line()
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;

            if isequal(event.Source.CurrentAxes,app.UIAxes_Rmain)
                app.TredsegSpinner.Value = app.TredsegSpinner.Value - verticalScrollCount;
                app.get_segmentation_T_R()
                app.redraw_segmentation_R()
             end
             if isequal(event.Source.CurrentAxes,app.UIAxes_Gmain)
                app.TgreensegSpinner.Value = app.TgreensegSpinner.Value - verticalScrollCount;
                app.get_segmentation_T_G()
                app.redraw_segmentation_G()
             end
            
        end

        % Value changed function: showlinesyCheckBox
        function showlinesyCheckBoxValueChanged(app, event)
            value = app.showlinesyCheckBox.Value;
            app.show_lines_r()
            app.show_lines_g()
        end

        % Button pushed function: deleteButton
        function deleteButtonPushed(app, event)
            app.delete_line_R();
            app.show_lines_r()
        end

        % Button pushed function: deleteButton_2
        function deleteButton_2Pushed(app, event)
            app.delete_line_G();
            app.show_lines_g()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes_Gright = uiaxes(app.UIFigure);
            title(app.UIAxes_Gright, 'Title')
            xlabel(app.UIAxes_Gright, 'X')
            ylabel(app.UIAxes_Gright, 'Y')
            zlabel(app.UIAxes_Gright, 'Z')
            app.UIAxes_Rleft = uiaxes(app.UIFigure);
            title(app.UIAxes_Rleft, 'Title')
            xlabel(app.UIAxes_Rleft, 'X')
            ylabel(app.UIAxes_Rleft, 'Y')
            zlabel(app.UIAxes_Rleft, 'Z')
            app.UIAxes_Rdown = uiaxes(app.UIFigure);
            title(app.UIAxes_Rdown, 'Title')
            xlabel(app.UIAxes_Rdown, 'X')
            ylabel(app.UIAxes_Rdown, 'Y')
            zlabel(app.UIAxes_Rdown, 'Z')
            app.UIAxes_Gdown = uiaxes(app.UIFigure);
            title(app.UIAxes_Gdown, 'Title')
            xlabel(app.UIAxes_Gdown, 'X')
            ylabel(app.UIAxes_Gdown, 'Y')
            zlabel(app.UIAxes_Gdown, 'Z')
            app.UIAxes_Gmain = uiaxes(app.UIFigure);
            title(app.UIAxes_Gmain, 'Title')
            xlabel(app.UIAxes_Gmain, 'X')
            ylabel(app.UIAxes_Gmain, 'Y')
            zlabel(app.UIAxes_Gmain, 'Z')
            app.UIAxes_Rmain = uiaxes(app.UIFigure);
            title(app.UIAxes_Rmain, 'Title')
            xlabel(app.UIAxes_Rmain, 'X')
            ylabel(app.UIAxes_Rmain, 'Y')
            zlabel(app.UIAxes_Rmain, 'Z')
            app.Spinner_Rmin = uispinner(app.UIFigure);
            app.Spinner_Rmin.Step = 5;
            app.Spinner_Rmin.RoundFractionalValues = 'on';
            app.Spinner_Rmin.ValueChangedFcn = createCallbackFcn(app, @Spinner_RminValueChanged, true);
            app.Spinner_Rmax = uispinner(app.UIFigure);
            app.Spinner_Rmax.Step = 5;
            app.Spinner_Rmax.RoundFractionalValues = 'on';
            app.Spinner_Rmax.ValueChangedFcn = createCallbackFcn(app, @Spinner_RmaxValueChanged, true);
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R';
            app.Spinner_Gmin = uispinner(app.UIFigure);
            app.Spinner_Gmin.Step = 5;
            app.Spinner_Gmin.RoundFractionalValues = 'on';
            app.Spinner_Gmin.ValueChangedFcn = createCallbackFcn(app, @Spinner_GminValueChanged, true);
            app.Spinner_Gmax = uispinner(app.UIFigure);
            app.Spinner_Gmax.Step = 5;
            app.Spinner_Gmax.RoundFractionalValues = 'on';
            app.Spinner_Gmax.ValueChangedFcn = createCallbackFcn(app, @Spinner_GmaxValueChanged, true);
            app.GLabel = uilabel(app.UIFigure);
            app.GLabel.Text = 'G';
            app.Spinner_Bmin = uispinner(app.UIFigure);
            app.Spinner_Bmin.Step = 5;
            app.Spinner_Bmin.RoundFractionalValues = 'on';
            app.Spinner_Bmin.ValueChangedFcn = createCallbackFcn(app, @Spinner_BminValueChanged, true);
            app.Spinner_Bmax = uispinner(app.UIFigure);
            app.Spinner_Bmax.Step = 5;
            app.Spinner_Bmax.RoundFractionalValues = 'on';
            app.Spinner_Bmax.ValueChangedFcn = createCallbackFcn(app, @Spinner_BmaxValueChanged, true);
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B';
            app.Button_prev = uibutton(app.UIFigure, 'push');
            app.Button_prev.ButtonPushedFcn = createCallbackFcn(app, @Button_prevPushed, true);
            app.Button_prev.Text = '<';
            app.Button_next = uibutton(app.UIFigure, 'push');
            app.Button_next.ButtonPushedFcn = createCallbackFcn(app, @Button_nextPushed, true);
            app.Button_next.Text = '>';
            app.RdSpinnerLabel = uilabel(app.UIFigure);
            app.RdSpinnerLabel.Text = 'R d';
            app.RdSpinner = uispinner(app.UIFigure);
            app.RdSpinner.RoundFractionalValues = 'on';
            app.RdSpinner.ValueChangedFcn = createCallbackFcn(app, @RdSpinnerValueChanged, true);
            app.RhSpinnerLabel = uilabel(app.UIFigure);
            app.RhSpinnerLabel.Text = 'R h';
            app.RhSpinner = uispinner(app.UIFigure);
            app.RhSpinner.Limits = [0 Inf];
            app.RhSpinner.ValueDisplayFormat = '%11.2g';
            app.RhSpinner.ValueChangedFcn = createCallbackFcn(app, @RhSpinnerValueChanged, true);
            app.RtSpinnerLabel = uilabel(app.UIFigure);
            app.RtSpinnerLabel.Text = 'R t';
            app.RtSpinner = uispinner(app.UIFigure);
            app.RtSpinner.Step = 3;
            app.RtSpinner.RoundFractionalValues = 'on';
            app.RtSpinner.ValueChangedFcn = createCallbackFcn(app, @RtSpinnerValueChanged, true);
            app.GdSpinnerLabel = uilabel(app.UIFigure);
            app.GdSpinnerLabel.Text = 'G d';
            app.GdSpinner = uispinner(app.UIFigure);
            app.GdSpinner.RoundFractionalValues = 'on';
            app.GdSpinner.ValueChangedFcn = createCallbackFcn(app, @GdSpinnerValueChanged, true);
            app.GhSpinnerLabel = uilabel(app.UIFigure);
            app.GhSpinnerLabel.Text = 'G h';
            app.GhSpinner = uispinner(app.UIFigure);
            app.GhSpinner.Limits = [0 Inf];
            app.GhSpinner.ValueDisplayFormat = '%11.2g';
            app.GhSpinner.ValueChangedFcn = createCallbackFcn(app, @GhSpinnerValueChanged, true);
            app.GtSpinnerLabel = uilabel(app.UIFigure);
            app.GtSpinnerLabel.Text = 'G t';
            app.GtSpinner = uispinner(app.UIFigure);
            app.GtSpinner.Step = 3;
            app.GtSpinner.RoundFractionalValues = 'on';
            app.GtSpinner.ValueChangedFcn = createCallbackFcn(app, @GtSpinnerValueChanged, true);
            app.numfilesLabel = uilabel(app.UIFigure);
            app.numfilesLabel.Text = '/100';
            app.showpointscCheckBox = uicheckbox(app.UIFigure);
            app.showpointscCheckBox.ValueChangedFcn = createCallbackFcn(app, @showpointscCheckBoxValueChanged, true);
            app.showpointscCheckBox.Text = 'show points (c)';
            app.showpointscCheckBox.Value = true;
            app.filenameLabel = uilabel(app.UIFigure);
            app.filenameLabel.Text = 'filename';
            app.newpointButton_R = uibutton(app.UIFigure, 'push');
            app.newpointButton_R.ButtonPushedFcn = createCallbackFcn(app, @newpointButton_RPushed, true);
            app.newpointButton_R.Text = 'new point (n)';
            app.filenumEditField = uieditfield(app.UIFigure, 'numeric');
            app.filenumEditField.Limits = [0 Inf];
            app.filenumEditField.RoundFractionalValues = 'on';
            app.filenumEditField.ValueChangedFcn = createCallbackFcn(app, @filenumEditFieldValueChanged, true);
            app.filenumEditField.Value = 1;
            app.dSpinner_init = uispinner(app.UIFigure);
            app.dSpinner_init.Limits = [1 Inf];
            app.dSpinner_init.RoundFractionalValues = 'on';
            app.dSpinner_init.ValueChangedFcn = createCallbackFcn(app, @dSpinner_initValueChanged, true);
            app.dSpinner_init.Value = 4;
            app.hSpinner_init = uispinner(app.UIFigure);
            app.hSpinner_init.Step = 3;
            app.hSpinner_init.Limits = [1 99];
            app.hSpinner_init.RoundFractionalValues = 'on';
            app.hSpinner_init.ValueChangedFcn = createCallbackFcn(app, @hSpinner_initValueChanged, true);
            app.hSpinner_init.Value = 90;
            app.tSpinner_init = uispinner(app.UIFigure);
            app.tSpinner_init.Step = 0.5;
            app.tSpinner_init.Limits = [0 100];
            app.tSpinner_init.ValueChangedFcn = createCallbackFcn(app, @tSpinner_initValueChanged, true);
            app.tSpinner_init.Value = 90;
            app.initpercSpinnerLabel = uilabel(app.UIFigure);
            app.initpercSpinnerLabel.Text = 'init-perc';
            app.initpercSpinner_minB = uispinner(app.UIFigure);
            app.initpercSpinner_minB.Step = 0.2;
            app.initpercSpinner_minB.Limits = [0 100];
            app.initpercSpinner_minB.ValueChangedFcn = createCallbackFcn(app, @initpercSpinner_minBValueChanged, true);
            app.initpercSpinner_minB.Value = 0.2;
            app.TredSpinnerLabel = uilabel(app.UIFigure);
            app.TredSpinnerLabel.Text = 'T red';
            app.TredSpinner = uispinner(app.UIFigure);
            app.TredSpinner.Step = 3;
            app.TredSpinner.RoundFractionalValues = 'on';
            app.TredSpinner.ValueChangedFcn = createCallbackFcn(app, @TredSpinnerValueChanged, true);
            app.TgreenSpinnerLabel = uilabel(app.UIFigure);
            app.TgreenSpinnerLabel.Text = 'T green';
            app.TgreenSpinner = uispinner(app.UIFigure);
            app.TgreenSpinner.Step = 3;
            app.TgreenSpinner.RoundFractionalValues = 'on';
            app.TgreenSpinner.ValueChangedFcn = createCallbackFcn(app, @TgreenSpinnerValueChanged, true);
            app.TSpinner_init = uispinner(app.UIFigure);
            app.TSpinner_init.Step = 0.2;
            app.TSpinner_init.Limits = [0 100];
            app.TSpinner_init.ValueChangedFcn = createCallbackFcn(app, @TSpinner_initValueChanged, true);
            app.TSpinner_init.Value = 99;
            app.editpointsvCheckBox = uicheckbox(app.UIFigure);
            app.editpointsvCheckBox.ValueChangedFcn = createCallbackFcn(app, @editpointsvCheckBoxValueChanged, true);
            app.editpointsvCheckBox.Text = 'edit points (v)';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.savesettingsButton = uibutton(app.UIFigure, 'push');
            app.savesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @savesettingsButtonPushed, true);
            app.savesettingsButton.Text = 'save settings';
            app.loadsettingsButton = uibutton(app.UIFigure, 'push');
            app.loadsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @loadsettingsButtonPushed, true);
            app.loadsettingsButton.Text = 'load settings';
            app.clearfolderButton = uibutton(app.UIFigure, 'push');
            app.clearfolderButton.ButtonPushedFcn = createCallbackFcn(app, @clearfolderButtonPushed, true);
            app.clearfolderButton.Text = 'clear folder';
            app.newpointButton_G = uibutton(app.UIFigure, 'push');
            app.newpointButton_G.ButtonPushedFcn = createCallbackFcn(app, @newpointButton_GPushed, true);
            app.newpointButton_G.Text = 'new point (n)';
            app.stepSpinnerLabel = uilabel(app.UIFigure);
            app.stepSpinnerLabel.Text = 'step';
            app.stepRSpinner = uispinner(app.UIFigure);
            app.stepRSpinner.Limits = [0 Inf];
            app.stepRSpinner.ValueChangedFcn = createCallbackFcn(app, @stepRSpinnerValueChanged, true);
            app.stepRSpinner.Value = 3;
            app.stepSpinnerLabel_2 = uilabel(app.UIFigure);
            app.stepSpinnerLabel_2.Text = 'step';
            app.stepGSpinner = uispinner(app.UIFigure);
            app.stepGSpinner.Limits = [0 Inf];
            app.stepGSpinner.ValueChangedFcn = createCallbackFcn(app, @stepGSpinnerValueChanged, true);
            app.stepGSpinner.Value = 3;
            app.initpercSpinner_maxB = uispinner(app.UIFigure);
            app.initpercSpinner_maxB.Step = 0.2;
            app.initpercSpinner_maxB.Limits = [0 100];
            app.initpercSpinner_maxB.ValueChangedFcn = createCallbackFcn(app, @initpercSpinner_maxBValueChanged, true);
            app.initpercSpinner_maxB.Value = 1;
            app.newpointcontbCheckBox = uicheckbox(app.UIFigure);
            app.newpointcontbCheckBox.ValueChangedFcn = createCallbackFcn(app, @newpointcontbCheckBoxValueChanged, true);
            app.newpointcontbCheckBox.Text = 'new point cont. (b)';
            app.showoriginalleftaltCheckBox = uicheckbox(app.UIFigure);
            app.showoriginalleftaltCheckBox.ValueChangedFcn = createCallbackFcn(app, @showoriginalleftaltCheckBoxValueChanged, true);
            app.showoriginalleftaltCheckBox.Text = 'show original (left alt)';
            app.showRGBxCheckBox = uicheckbox(app.UIFigure);
            app.showRGBxCheckBox.ValueChangedFcn = createCallbackFcn(app, @showRGBxCheckBoxValueChanged, true);
            app.showRGBxCheckBox.Text = 'show RGB (x)';
            app.initpercSpinner_minG = uispinner(app.UIFigure);
            app.initpercSpinner_minG.Step = 0.2;
            app.initpercSpinner_minG.Limits = [0 100];
            app.initpercSpinner_minG.ValueChangedFcn = createCallbackFcn(app, @initpercSpinner_minGValueChanged, true);
            app.initpercSpinner_minG.Value = 0.2;
            app.initpercSpinner_maxG = uispinner(app.UIFigure);
            app.initpercSpinner_maxG.Step = 0.2;
            app.initpercSpinner_maxG.Limits = [0 100];
            app.initpercSpinner_maxG.ValueChangedFcn = createCallbackFcn(app, @initpercSpinner_maxGValueChanged, true);
            app.initpercSpinner_maxG.Value = 1;
            app.initpercSpinner_minR = uispinner(app.UIFigure);
            app.initpercSpinner_minR.Step = 0.2;
            app.initpercSpinner_minR.Limits = [0 100];
            app.initpercSpinner_minR.ValueChangedFcn = createCallbackFcn(app, @initpercSpinner_minRValueChanged, true);
            app.initpercSpinner_minR.Value = 0.2;
            app.initpercSpinner_maxR = uispinner(app.UIFigure);
            app.initpercSpinner_maxR.Step = 0.2;
            app.initpercSpinner_maxR.Limits = [0 100];
            app.initpercSpinner_maxR.ValueChangedFcn = createCallbackFcn(app, @initpercSpinner_maxRValueChanged, true);
            app.initpercSpinner_maxR.Value = 1;
            app.GLabel_2 = uilabel(app.UIFigure);
            app.GLabel_2.Text = 'G';
            app.BLabel_2 = uilabel(app.UIFigure);
            app.BLabel_2.Text = 'B';
            app.RLabel_2 = uilabel(app.UIFigure);
            app.RLabel_2.Text = 'R';
            app.loaded_or_initLabel = uilabel(app.UIFigure);
            app.loaded_or_initLabel.Text = 'loaded_or_init';
            app.savedLabel = uilabel(app.UIFigure);
            app.savedLabel.Text = 'saved?';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.TredsegSpinnerLabel = uilabel(app.UIFigure);
            app.TredsegSpinnerLabel.Text = 'T red seg';
            app.TredsegSpinner = uispinner(app.UIFigure);
            app.TredsegSpinner.ValueChangedFcn = createCallbackFcn(app, @TredsegSpinnerValueChanged, true);
            app.TredsegSpinner.Value = 100;
            app.pTGSpinnerLabel = uilabel(app.UIFigure);
            app.pTGSpinnerLabel.Text = 'pTG';
            app.pTGSpinner = uispinner(app.UIFigure);
            app.pTGSpinner.Value = 20;
            app.pTRSpinnerLabel = uilabel(app.UIFigure);
            app.pTRSpinnerLabel.Text = 'pTR';
            app.pTRSpinner = uispinner(app.UIFigure);
            app.pTRSpinner.Value = 20;
            app.deleteButton = uibutton(app.UIFigure, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteButtonPushed, true);
            app.deleteButton.Text = 'delete (,)';
            app.deleteButton_2 = uibutton(app.UIFigure, 'push');
            app.deleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @deleteButton_2Pushed, true);
            app.deleteButton_2.Text = 'delete (,)';
            app.drawRButton = uibutton(app.UIFigure, 'push');
            app.drawRButton.ButtonPushedFcn = createCallbackFcn(app, @drawRButtonPushed, true);
            app.drawRButton.Text = 'draw (m)';
            app.drawGButton = uibutton(app.UIFigure, 'push');
            app.drawGButton.ButtonPushedFcn = createCallbackFcn(app, @drawGButtonPushed, true);
            app.drawGButton.Text = 'draw(m)';
            app.showmasksyCheckBox = uicheckbox(app.UIFigure);
            app.showmasksyCheckBox.ValueChangedFcn = createCallbackFcn(app, @showmasksyCheckBoxValueChanged, true);
            app.showmasksyCheckBox.Text = 'show masks (y)';
            app.showmasksyCheckBox.Value = true;
            app.TgreensegSpinnerLabel = uilabel(app.UIFigure);
            app.TgreensegSpinnerLabel.Text = 'T green seg';
            app.TgreensegSpinner = uispinner(app.UIFigure);
            app.TgreensegSpinner.ValueChangedFcn = createCallbackFcn(app, @TgreensegSpinnerValueChanged, true);
            app.showlinesyCheckBox = uicheckbox(app.UIFigure);
            app.showlinesyCheckBox.ValueChangedFcn = createCallbackFcn(app, @showlinesyCheckBoxValueChanged, true);
            app.showlinesyCheckBox.Text = 'show lines (y)';
            app.showlinesyCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = two_colors_lines

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end