classdef semiauto_appdes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        img_maing      matlab.ui.control.UIAxes
        img_down       matlab.ui.control.UIAxes
        img_right      matlab.ui.control.UIAxes
        okButton       matlab.ui.control.Button
        dropButton     matlab.ui.control.Button
        rSpinner_2     matlab.ui.control.Spinner
        gSpinner_2     matlab.ui.control.Spinner
        bSpinner_2     matlab.ui.control.Spinner
        rSpinnerLabel  matlab.ui.control.Label
        rSpinner       matlab.ui.control.Spinner
        gSpinnerLabel  matlab.ui.control.Label
        gSpinner       matlab.ui.control.Spinner
        bSpinnerLabel  matlab.ui.control.Label
        bSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        pos
        binaryResuslts
        rImg_main
        gImg_main
        bImg_main
        rImg_bottom
        gImg_bottom
        bImg_bottom
        rImg_right
        gImg_right
        bImg_right
        rMin
        rMax
        gMin
        gMax
        bMin
        bMax
        drop
        maskNuklei_main
        maskNuklei_bottom
        maskNuklei_right
        maskFoci_main
        maskFoci_bottom
        maskFoci_right
        resize_factor
    end
    
    
    methods (Access = private)
        
        function update_all(app)
%             axes(app.img_maing)
            color=cat(3,mat2gray(app.rImg_main,[app.rMin*app.rSpinner.Value  app.rMax*app.rSpinner_2.Value]),...
                mat2gray(app.gImg_main,[app.gMin*app.gSpinner.Value  app.gMax*app.gSpinner.Value]),...
                mat2gray(app.bImg_main,[app.bMin*app.bSpinner.Value  app.bMax*app.bSpinner.Value]));
            imshow(color,'Parent',app.img_maing)
            
            
            color=cat(3,mat2gray(app.rImg_bottom,[app.rMin*app.rSpinner.Value  app.rMax*app.rSpinner.Value]),...
                mat2gray(app.gImg_bottom,[app.gMin*app.gSpinner.Value  app.gMax*app.gSpinner.Value]),...
                mat2gray(app.bImg_bottom,[app.bMin*app.bSpinner.Value  app.bMax*app.bSpinner.Value]));
            imshow(color,'Parent',app.img_down)
            
            
            color=cat(3,mat2gray(app.rImg_right,[app.rMin*app.rSpinner.Value  app.rMax*app.rSpinner.Value]),...
                mat2gray(app.gImg_right,[app.gMin*app.gSpinner.Value  app.gMax*app.gSpinner.Value]),...
                mat2gray(app.bImg_right,[app.bMin*app.bSpinner.Value  app.bMax*app.bSpinner.Value]));
            imshow(color,'Parent',app.img_right)
            
        end
        
        function update_points(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, r, g, b, mask_nuclei, mask_foci)
            drawnow;
%             set(get(app.UIFigure,'JavaFrame'),'Maximized',1);
            
%             H = uitoolbar('parent',app.img_maing);
%             C = uitoolfactory(H,'Exploration.ZoomIn');
%             C = uitoolfactory(H,'Exploration.ZoomOut');


            tmp =regionprops3(mask_foci,"Centroid");
            app.pos= tmp.Centroid;  
            
            app.binaryResuslts=zeros(1,size(app.pos,1));
            
            
            app.rImg_main=max(r,[],3);
            app.gImg_main=max(g,[],3);
            app.bImg_main=max(b,[],3);
            
            app.rImg_right=squeeze(max(r,[],2));
            app.gImg_right=squeeze(max(g,[],2));
            app.bImg_right=squeeze(max(b,[],2));
            
            app.rImg_bottom=squeeze(max(r,[],1))';
            app.gImg_bottom=squeeze(max(g,[],1))';
            app.bImg_bottom=squeeze(max(b,[],1))';
            
            app.rMin=min(app.rImg_bottom(:));
            app.rMax=max(app.rImg_bottom(:));
            app.gMin=min(app.gImg_bottom(:));
            app.gMax=max(app.gImg_bottom(:));
            app.bMin=min(app.bImg_bottom(:));
            app.bMax=max(app.bImg_bottom(:));
            
            app.drop=0;
            
            app.maskNuklei_main=max(mask_nuclei,[],3);
            app.maskNuklei_bottom=squeeze(max(mask_nuclei,[],2));
            app.maskNuklei_right=squeeze(max(mask_nuclei,[],1))';
            
            app.maskFoci_main=max(mask_nuclei,[],3);
            app.maskFoci_bottom=squeeze(max(mask_nuclei,[],2));
            app.maskFoci_right=squeeze(max(mask_nuclei,[],1))';
            
%             app.resize_factor=4;
%             
%             app.rImg_right=imresize(app.rImg_right,[size(app.rImg_right,1),size(app.rImg_right,2)*app.resize_factor]);
%             app.gImg_right=imresize(app.gImg_right,[size(app.rImg_right,1),size(app.rImg_right,2)*app.resize_factor]);
%             app.bImg_right=imresize(app.bImg_right,[size(app.bImg_right,1),size(app.bImg_right,2)*app.resize_factor]);
%             
%             app.rImg_bottom=imresize(app.rImg_bottom,[size(app.rImg_bottom,1)*app.resize_factor,size(app.rImg_bottom,2)]);
%             app.gImg_bottom=imresize(app.gImg_bottom,[size(app.rImg_bottom,1)*app.resize_factor,size(app.rImg_bottom,2)]);
%             app.bImg_bottom=imresize(app.bImg_bottom,[size(app.bImg_bottom,1)*app.resize_factor,size(app.bImg_bottom,2)]);
            
            app.update_all()
            
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             assignin('base',variablename,variable)
            delete(app)
            
        end

        % Button pushed function: dropButton
        function dropButtonPushed(app, event)
            app.drop=1;
            delete(app)
        end

        % Value changed function: rSpinner
        function rSpinnerValueChanged(app, event)
            app.update_all() 
        end

        % Value changed function: rSpinner_2
        function rSpinner_2ValueChanged(app, event)
            app.update_all()
        end

        % Value changed function: gSpinner
        function gSpinnerValueChanged(app, event)
            app.update_all()
        end

        % Value changed function: gSpinner_2
        function gSpinner_2ValueChanged(app, event)
            app.update_all()
        end

        % Value changed function: bSpinner
        function bSpinnerValueChanged(app, event)
            app.update_all()
        end

        % Value changed function: bSpinner_2
        function bSpinner_2ValueChanged(app, event)
            app.update_all()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.img_maing = uiaxes(app.UIFigure);
            title(app.img_maing, 'Title')
            xlabel(app.img_maing, 'X')
            ylabel(app.img_maing, 'Y')
            app.img_down = uiaxes(app.UIFigure);
            title(app.img_down, 'Title')
            xlabel(app.img_down, 'X')
            ylabel(app.img_down, 'Y')
            app.img_right = uiaxes(app.UIFigure);
            title(app.img_right, 'Title')
            xlabel(app.img_right, 'X')
            ylabel(app.img_right, 'Y')
            app.okButton = uibutton(app.UIFigure, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'ok';
            app.dropButton = uibutton(app.UIFigure, 'push');
            app.dropButton.ButtonPushedFcn = createCallbackFcn(app, @dropButtonPushed, true);
            app.dropButton.Text = 'drop';
            app.rSpinner_2 = uispinner(app.UIFigure);
            app.rSpinner_2.Step = 0.1;
            app.rSpinner_2.Limits = [0.1 10];
            app.rSpinner_2.ValueChangedFcn = createCallbackFcn(app, @rSpinner_2ValueChanged, true);
            app.rSpinner_2.Value = 1;
            app.gSpinner_2 = uispinner(app.UIFigure);
            app.gSpinner_2.Step = 0.1;
            app.gSpinner_2.Limits = [0.1 10];
            app.gSpinner_2.ValueChangedFcn = createCallbackFcn(app, @gSpinner_2ValueChanged, true);
            app.gSpinner_2.Value = 1;
            app.bSpinner_2 = uispinner(app.UIFigure);
            app.bSpinner_2.Step = 0.1;
            app.bSpinner_2.Limits = [0.1 10];
            app.bSpinner_2.ValueChangedFcn = createCallbackFcn(app, @bSpinner_2ValueChanged, true);
            app.bSpinner_2.Value = 1;
            app.rSpinnerLabel = uilabel(app.UIFigure);
            app.rSpinnerLabel.Text = 'r ';
            app.rSpinner = uispinner(app.UIFigure);
            app.rSpinner.Step = 0.1;
            app.rSpinner.Limits = [0 10];
            app.rSpinner.ValueChangedFcn = createCallbackFcn(app, @rSpinnerValueChanged, true);
            app.rSpinner.Value = 1;
            app.gSpinnerLabel = uilabel(app.UIFigure);
            app.gSpinnerLabel.Text = 'g ';
            app.gSpinner = uispinner(app.UIFigure);
            app.gSpinner.Step = 0.1;
            app.gSpinner.Limits = [0 10];
            app.gSpinner.ValueChangedFcn = createCallbackFcn(app, @gSpinnerValueChanged, true);
            app.gSpinner.Value = 1;
            app.bSpinnerLabel = uilabel(app.UIFigure);
            app.bSpinnerLabel.Text = 'b ';
            app.bSpinner = uispinner(app.UIFigure);
            app.bSpinner.Step = 0.1;
            app.bSpinner.Limits = [0 10];
            app.bSpinner.ValueChangedFcn = createCallbackFcn(app, @bSpinnerValueChanged, true);
            app.bSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = semiauto_appdes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end